classdef deeplearningApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        NEWButton      matlab.ui.control.Button
        ALEXButton     matlab.ui.control.Button
        SQUEEZEButton  matlab.ui.control.Button
        RightPanel     matlab.ui.container.Panel
        confmat        matlab.ui.control.UIAxes
        model          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SQUEEZEButton
        function SQUEEZEButtonPushed(app, event)
            unzip("C:\Abirami\sem7\fyp\dataset\deeplearning.zip");
            imds = imageDatastore('deeplearning', ...
                'IncludeSubfolders',true, ...
                'LabelSource','foldernames');
            
            
            [imdsTrain,imdsValidation] = splitEachLabel(imds,0.8,'randomized');
            
            
            net = squeezenet;
            
            analyzeNetwork(net);
            inputSize = net.Layers(1).InputSize;
            
            
            lgraph = layerGraph(net); 
            
            
            numClasses = numel(categories(imdsTrain.Labels));
            
            newConvLayer =  convolution2dLayer([1, 1],numClasses,'WeightLearnRateFactor',10,'BiasLearnRateFactor',10,"Name",'new_conv');
            lgraph = replaceLayer(lgraph,'conv10',newConvLayer);
            
            
            newClassificatonLayer = classificationLayer('Name','new_classoutput');
            lgraph = replaceLayer(lgraph,'ClassificationLayer_predictions',newClassificatonLayer);
            
            
            pixelRange = [-30 30];
            imageAugmenter = imageDataAugmenter( ...
                'RandXReflection',true, ...
                'RandXTranslation',pixelRange, ...
                'RandYTranslation',pixelRange);
            augimdsTrain = augmentedImageDatastore(inputSize(1:2),imdsTrain, ...
                'DataAugmentation',imageAugmenter);
            
            
            augimdsValidation = augmentedImageDatastore(inputSize(1:2),imdsValidation);
            
            options = trainingOptions('adam', ...
                'MiniBatchSize',11, ...
                'MaxEpochs',16, ...
                'InitialLearnRate',0.00001, ...
                'Shuffle','every-epoch', ...
                'ValidationData',imdsValidation, ...
                'MiniBatchSize',10,...
                'ValidationFrequency',3, ...
                'Verbose',false, ...,
                'Plots','training-progress');
            
            netTransfer = trainNetwork(augimdsTrain,lgraph,options);
            
            [YPred,scores] = classify(netTransfer,augimdsValidation);
            
            YValidation = imdsValidation.Labels;
            accuracy = mean(YPred == YValidation);
            
            figure, plotconfusion(YValidation,YPred);

            figure(app.UIFigure);
            imshow(netTransfer,'parent',app.model);   

            figure(app.UIFigure);
            imshow(plotconfusion(YValidation,YPred),'parent',app.confmat);   


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {234, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SQUEEZEButton = uibutton(app.LeftPanel, 'push');
            app.SQUEEZEButton.ButtonPushedFcn = createCallbackFcn(app, @SQUEEZEButtonPushed, true);
            app.SQUEEZEButton.Text = 'SQUEEZE';
            app.ALEXButton = uibutton(app.LeftPanel, 'push');
            app.ALEXButton.Text = 'ALEX';
            app.NEWButton = uibutton(app.LeftPanel, 'push');
            app.NEWButton.Text = 'NEW';
            app.RightPanel = uipanel(app.GridLayout);
            app.model = uiaxes(app.RightPanel);
            title(app.model, 'Title')
            xlabel(app.model, 'X')
            ylabel(app.model, 'Y')
            zlabel(app.model, 'Z')
            app.confmat = uiaxes(app.RightPanel);
            title(app.confmat, 'Title')
            xlabel(app.confmat, 'X')
            ylabel(app.confmat, 'Y')
            zlabel(app.confmat, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = deeplearningApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end