classdef imageClassification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ROIABButton                  matlab.ui.control.Button
        ROIRFButton                  matlab.ui.control.Button
        ROIDTButton                  matlab.ui.control.Button
        ROIKNNButton                 matlab.ui.control.Button
        PROCESSButton_6              matlab.ui.control.Button
        CENTROIDABButton             matlab.ui.control.Button
        CENTROIDRFButton             matlab.ui.control.Button
        CENTROIDDTButton             matlab.ui.control.Button
        CENTROIDKNNButton            matlab.ui.control.Button
        PROCESSButton_5              matlab.ui.control.Button
        Cluster6Button               matlab.ui.control.Button
        Cluster5Button               matlab.ui.control.Button
        Cluster4Button               matlab.ui.control.Button
        Cluster3Button               matlab.ui.control.Button
        Cluster2Button               matlab.ui.control.Button
        Cluster1Button               matlab.ui.control.Button
        TESTDATARESULTTextArea       matlab.ui.control.TextArea
        TESTDATARESULTTextAreaLabel  matlab.ui.control.Label
        FEATUREEXTRACTIONButton      matlab.ui.control.Button
        HIPPOCLASSIFYABButton        matlab.ui.control.Button
        HIPPOCLASSIFYRFButton        matlab.ui.control.Button
        HIPPOCLASSIFYDTButton        matlab.ui.control.Button
        HIPPOCLASSIFYKNNButton       matlab.ui.control.Button
        PROCESSButton_3              matlab.ui.control.Button
        PROCESSButton_2              matlab.ui.control.Button
        PIXELSPERCLUSTERTextArea     matlab.ui.control.TextArea
        PERFORMANCEEVALUATIONREPORTTextArea  matlab.ui.control.TextArea
        SKULLCLASSIFYABButton        matlab.ui.control.Button
        SKULLCLASSIFYRFButton        matlab.ui.control.Button
        SKULLCLASSIFYDTButton        matlab.ui.control.Button
        SKULLCLASSIFYKNNButton       matlab.ui.control.Button
        SKULLSTRIPPINGACCURACYEditField  matlab.ui.control.NumericEditField
        SKULLSTRIPPINGACCURACYEditFieldLabel  matlab.ui.control.Label
        PROCESSButton                matlab.ui.control.Button
        IMPORTButton                 matlab.ui.control.Button
        roiImgAxes                   matlab.ui.control.UIAxes
        clusterImg_3                 matlab.ui.control.UIAxes
        clusterImg_2                 matlab.ui.control.UIAxes
        clusterImg                   matlab.ui.control.UIAxes
        hippoImg                     matlab.ui.control.UIAxes
        strippedImg                  matlab.ui.control.UIAxes
        inpImg                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        LoadedImg % Description
        skullImg % Description
        hippocampImg % Description
        manuClusterImg % Description
        se % Description
        finalSkullImg % Description
        L1 % Description
        clusterInfo
        rows
        columns
        numberOfColorChannels
        one
        two
        three
        four
        five
        six
        P1
        P2
        P3
        P4
        P5
        P6
        middleRow
        origImg
        group
        m1
        m2
        m3
        m4
        m5
        m6
        currentCluster
        connImg
        
        roiImg % Description
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IMPORTButton
        function getImg(app, event)
            [file,path]= uigetfile('*.*');           
            newstr=extract(path,"Final CN JPEG");
            if newstr=="Final CN JPEG"
                app.group='Normal';
            else 
                app.group='Abnormal';
            end          
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            app.LoadedImg  = imread(fullfile(path,file));
            app.origImg=app.LoadedImg;
            app.LoadedImg=adapthisteq(app.LoadedImg,'clipLimit',0.02,'Distribution','rayleigh');
            imshow(app.LoadedImg ,'parent',app.inpImg);
        end

        % Button pushed function: PROCESSButton
        function extractInpImg(app, event)
            app.skullImg=app.LoadedImg > 55;
            app.skullImg=bwareaopen(app.skullImg,10);
            app.skullImg=imfill(app.skullImg,'holes');
            app.se=strel('disk',15,0);
            app.skullImg=imerode(app.skullImg,app.se);
            app.finalSkullImg=app.LoadedImg;
            app.finalSkullImg(~app.skullImg)=0;            
            figure(app.UIFigure);
            imshow(app.finalSkullImg,'parent',app.strippedImg);
            app.SKULLSTRIPPINGACCURACYEditField.Value=(sum(~app.finalSkullImg(:))/(sum(~app.origImg(:))))*100;         
        end

        % Button pushed function: PROCESSButton_2
        function hipoExtract(app, event)
            [rows, columns, numberOfColorChannels] = size(app.LoadedImg);
            middleRow = round(rows/2);
            middleColumn = round(columns/2);
            app.hippocampImg=app.LoadedImg;
            [rows, cols, chans, ~] = size(app.hippocampImg);
            for r = 1:rows
                for s=1:cols
                    if(r<middleRow | r>middleRow+70 | s<70 | s>170)
                        app.hippocampImg(r,s)=0;
                    else
                        app.hippocampImg(r,s)=app.hippocampImg(r,s);
                    end
                end
            end
            figure(app.UIFigure);
            imshow(app.hippocampImg,'parent',app.hippoImg);       
        end

        % Button pushed function: PROCESSButton_3
        function clusterProcess(app, event)
            [pixel,centroid]=imsegkmeans(app.LoadedImg,6);
            app.manuClusterImg= labeloverlay(app.LoadedImg,pixel);
            app.P1="";
            app.P2="";
            app.P3="";
            app.P4="";
            app.P5="";
            app.P6="";
            app.one=0;
            app.two=0;
            app.three=0;
            app.four=0;
            app.five=0;
            app.six=0;

            app.m1=app.hippocampImg;
            app.m2=app.hippocampImg;
            app.m3=app.hippocampImg;
            app.m4=app.hippocampImg;
            app.m5=app.hippocampImg;
            app.m6=app.hippocampImg;

            [rows, cols, chans, ~] = size(app.hippocampImg);

             for r = 1:rows
                for s=1:cols
                    if(pixel(r,s)==1)
                        app.m1(r,s)=app.LoadedImg(r,s);
                        app.m2(r,s)=0;
                        app.m3(r,s)=0;
                        app.m4(r,s)=0;
                        app.m5(r,s)=0;
                        app.m6(r,s)=0;
                        if(app.P1=="")
                            app.P1 = sprintf(app.manuClusterImg(r,s,1)+" "+app.manuClusterImg(r,s,2)+" "+app.manuClusterImg(r,s,3));
                        end
                        app.one=app.one+1;
                    end
                    if(pixel(r,s)==2)
                        app.m1(r,s)=0;
                        app.m2(r,s)=app.LoadedImg(r,s);
                        app.m3(r,s)=0;
                        app.m4(r,s)=0;
                        app.m5(r,s)=0;
                        app.m6(r,s)=0;
                        if(app.P2=="")
                            app.P2 = sprintf(app.manuClusterImg(r,s,1)+" "+app.manuClusterImg(r,s,2)+" "+app.manuClusterImg(r,s,3));%impixel(app.manuClusterImg,r,s);
                        end
                        app.two=app.two+1;
                    end
                    if(pixel(r,s)==3)
                        app.m1(r,s)=0;
                        app.m2(r,s)=0;
                        app.m3(r,s)=app.LoadedImg(r,s);
                        app.m4(r,s)=0;
                        app.m5(r,s)=0;
                        app.m6(r,s)=0;

                        if(app.P3=="")   
                            app.P3 = sprintf(app.manuClusterImg(r,s,1)+" "+app.manuClusterImg(r,s,2)+" "+app.manuClusterImg(r,s,3));
                        end
                        app.three=app.three+1;
                    end
                    if(pixel(r,s)==4)
                        app.m1(r,s)=0;
                        app.m2(r,s)=0;
                        app.m3(r,s)=0;
                        app.m4(r,s)=app.LoadedImg(r,s);
                        app.m5(r,s)=0;
                        app.m6(r,s)=0;

                        if(app.P4=="")
                            app.P4 = sprintf(app.manuClusterImg(r,s,1)+" "+app.manuClusterImg(r,s,2)+" "+app.manuClusterImg(r,s,3));
                        end
                        app.four=app.four+1;
                    end
                    if(pixel(r,s)==5)
                        app.m1(r,s)=0;
                        app.m2(r,s)=0;
                        app.m3(r,s)=0;
                        app.m4(r,s)=0;
                        app.m5(r,s)=app.LoadedImg(r,s);
                        app.m6(r,s)=0;

                        if(app.P5=="")
                            app.P5 = sprintf(app.manuClusterImg(r,s,1)+" "+app.manuClusterImg(r,s,2)+" "+app.manuClusterImg(r,s,3));
                        end
                        app.five=app.five+1;
                    end
                    if(pixel(r,s)==6)
                        app.m1(r,s)=0;
                        app.m2(r,s)=0;
                        app.m3(r,s)=0;
                        app.m4(r,s)=0;
                        app.m5(r,s)=0;
                        app.m6(r,s)=app.LoadedImg(r,s);

                        if(app.P6=="")
                            app.P6 = sprintf(app.manuClusterImg(r,s,1)+" "+app.manuClusterImg(r,s,2)+" "+app.manuClusterImg(r,s,3));
                        end
                        app.six=app.six+1;
                    end
                end
             end
             figure(app.UIFigure);    
             imshow(app.manuClusterImg,'parent',app.clusterImg);  
             app.clusterInfo=sprintf("CLUSTER INFO\n\n\n\nCluster 1 no of pixels = "+num2str(app.one)+"\n\ncolor "+(app.P1)+"\n\n\nCluster 2 no of pixels = "+num2str(app.two)+"\n\ncolor "+(app.P2)+"\n\n\nCluster 3 no of pixels = "+num2str(app.three)+"\n\ncolor "+(app.P3)+"\n\n\nCluster 4 no of pixels = "+num2str(app.four)+"\n\ncolor "+(app.P4)+"\n\n\nCluster 5 no of pixels = "+num2str(app.five)+"\n\ncolor "+(app.P5)+"\n\n\nCluster 6 no of pixels = "+num2str(app.six)+"\n\ncolor "+(app.P6));
             app.PIXELSPERCLUSTERTextArea.Value=app.clusterInfo;
     

        end

        % Button pushed function: Cluster1Button
        function placeImg1(app, event)
            imshow(app.m1,'parent',app.clusterImg_2 );
            app.currentCluster=app.m1;
        end

        % Button pushed function: Cluster2Button
        function placeImg2(app, event)
            imshow(app.m2,'parent',app.clusterImg_2 );
            app.currentCluster=app.m2;
        end

        % Button pushed function: Cluster3Button
        function placeImg3(app, event)
            imshow(app.m3,'parent',app.clusterImg_2 );
            app.currentCluster=app.m3;
        end

        % Button pushed function: Cluster4Button
        function placeImg4(app, event)
            imshow(app.m4,'parent',app.clusterImg_2 );
            app.currentCluster=app.m4;
        end

        % Button pushed function: Cluster5Button
        function placeImg5(app, event)
            imshow(app.m5,'parent',app.clusterImg_2 );
            app.currentCluster=app.m5;
        end

        % Button pushed function: Cluster6Button
        function placeImg6(app, event)
            imshow(app.m6,'parent',app.clusterImg_2 );
            app.currentCluster=app.m6;
        end

        % Button pushed function: PROCESSButton_6
        function roiRegion(app, event)
            
            h=[0 1 1;
            1 1 1;
            1 1 0]/7;
            im_mean=imfilter(app.LoadedImg,h);
            figure 
            hold on
            se = strel('disk',3);
            im_top=im_mean-imtophat(im_mean,se);
            im_adjust=imadjust(im_top);
            [L,cen] = imsegkmeans(im_adjust,3);
            B = labeloverlay(im_adjust,L);
            T=(max(cen));
            BW = imbinarize(im_adjust,0.8);
            t = T;
            ind_below = (im_adjust < t);
            ind_above = (im_adjust >= t);
            im_adjust(ind_below) = 0;
            im_adjust(ind_above) = 255;
            cc4 = bwconncomp(im_adjust,4);
            L4 = labelmatrix(cc4);
            RGB_label = label2rgb(L4,@copper,"c","shuffle");
            app.connImg=app.LoadedImg;
            app.connImg(~BW)=0;  
            
            binaryImg1=app.LoadedImg
            binaryImg2=app.LoadedImg
            
            
            bw = bwareafilt(BW, 1);
            bloc = bwboundaries(bw,'noholes');
            roipos = fliplr(bloc{1});
            imshow(app.LoadedImg);
            hfh = drawfreehand('Position', roipos);
            binaryImg1=createMask(hfh)
            
            bw = bwareafilt(BW, 2);
            bloc = bwboundaries(bw,'noholes');
            roipos = fliplr(bloc{1});
            imshow(app.LoadedImg);
            hfh = drawfreehand('Position', roipos);
            binaryImg2=createMask(hfh)
            
            finalMask=imfuse(binaryImg1,binaryImg2)
            finalMask=rgb2gray(finalMask)
            imshow(finalMask)
            finalSegImg=app.LoadedImg
            finalSegImg(~finalMask)=0;   
            app.roiImg=finalSegImg

            imshow(finalSegImg,'parent',app.roiImgAxes);   
        end

        % Button pushed function: PROCESSButton_5
        function connectedComp(app, event)
            h=[0 1 1;
            1 1 1;
            1 1 0]/7;
            im_mean=imfilter(app.LoadedImg,h);
            %im_mean = imlocalbrighten(im_mean);
            figure 
            hold on
            se = strel('disk',3);
            im_top=im_mean-imtophat(im_mean,se);
            im_adjust=imadjust(im_top);

            [L,cen] = imsegkmeans(im_adjust,3);
            B = labeloverlay(im_adjust,L);
            T=(max(cen));
            BW = imbinarize(im_adjust,0.8);
            %display(im_k)

            % threshold 
            t = T;
            % find values below
            ind_below = (im_adjust < t);
            % find values above
            ind_above = (im_adjust >= t);
            % set values below to black
            im_adjust(ind_below) = 0;
            % set values above to white
            im_adjust(ind_above) = 255;

            %disp(im_adjust)

            cc4 = bwconncomp(im_adjust,4);

            L4 = labelmatrix(cc4);
            RGB_label = label2rgb(L4,@copper,"c","shuffle");
            app.connImg=app.LoadedImg;
            app.connImg(~BW)=0;  


            imshow(app.connImg,'parent',app.clusterImg_3 );

        end

        % Button pushed function: FEATUREEXTRACTIONButton
        function datasetcreation(app, event)
            %skull GLCM                                                                                   
            excel = actxserver('Excel.Application');  
            excelWorkbook = excel.Workbooks.Add(1);   
            excelSheet = excel.ActiveSheet;        
     
            excelSheet.Range('A1').Value = "Mean";
            excelSheet.Range('B1').Value = "Standard Deviation";
            excelSheet.Range('C1').Value = "Variance";
            excelSheet.Range('D1').Value = "Entropy";
            excelSheet.Range('E1').Value = "Maximum G Intensity";
            excelSheet.Range('F1').Value = "Minimum G Intensity";
            excelSheet.Range('G1').Value = "Skewness";
            excelSheet.Range('H1').Value = "Kurtosis";
            excelSheet.Range('I1').Value = "Contrast";
            excelSheet.Range('J1').Value = "Correlation";
            excelSheet.Range('K1').Value = "Energy";
            excelSheet.Range('L1').Value = "Homogenity";
            excelSheet.Range('M1').Value = "AutoCorrelation";
            excelSheet.Range('N1').Value = "Cluster Prominence";
            excelSheet.Range('O1').Value = "Cluster Shade";
            excelSheet.Range('P1').Value = "Dissimilarity";
            excelSheet.Range('Q1').Value = "MaximumProbability";
            excelSheet.Range('R1').Value = "Sum of sqaures";
            excelSheet.Range('S1').Value = "Sum average";
            excelSheet.Range('T1').Value = "Sum Variance";
            excelSheet.Range('U1').Value = "Sum entropy ";
            excelSheet.Range('V1').Value = "Difference variance";
            excelSheet.Range('W1').Value = "Difference Entropy";
            excelSheet.Range('X1').Value = "Information measure of correlation1";
            excelSheet.Range('Y1').Value = "Information measure of correlation2";
            excelSheet.Range('Z1').Value = "Inverse Difference";
            excelSheet.Range('AA1').Value = "Inverse Difference Normalized";
            excelSheet.Range('AB1').Value = "Inverse Difference Moment Normalized";
            excelSheet.Range('AC1').Value = "Ground Truth Value";
            
            start=2;

            cellA=append('A',int2str(start));
            lefted = num2str(mean2(app.finalSkullImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellA).Value = aText;
            
            cellB=append('B',int2str(start));
            l1=double(app.finalSkullImg);
            lefted =num2str(std(l1(:))); 
            aText=append(lefted);
            excelSheet.Range(cellB).Value = aText;
                            
            cellC=append('C',int2str(start));
            l1=double(app.finalSkullImg);
            lefted =num2str(var(l1(:)));
            aText=append(lefted);
            excelSheet.Range(cellC).Value = aText;
            
            cellD=append('D',int2str(start));
            lefted = num2str(entropy(app.finalSkullImg));
            aText=append(lefted);
            excelSheet.Range(cellD).Value = aText;           
            
            cellE=append('E',int2str(start));
            lefted = num2str(max(app.finalSkullImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellE).Value = aText;
            
            cellF=append('F',int2str(start));
            lefted = num2str(min(app.finalSkullImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellF).Value = aText;
            
            cellG=append('G',int2str(start));
            lefted = num2str(skewness(app.finalSkullImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellG).Value = aText;
            
            cellH=append('H',int2str(start));
            lefted = num2str(kurtosis(app.finalSkullImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellH).Value = aText;
            
            cellI=append('I',int2str(start));
            lefted =num2str(graycoprops(app.finalSkullImg).Contrast);
            aText=append(lefted);
            excelSheet.Range(cellI).Value = aText;
            
            cellJ=append('J',int2str(start));
            lefted =num2str(graycoprops(app.finalSkullImg).Correlation);
            aText=append(lefted);
            excelSheet.Range(cellJ).Value = aText;            
                        
            cellK=append('K',int2str(start));
            lefted =num2str(graycoprops(app.finalSkullImg).Energy);
            aText=append(lefted);
            excelSheet.Range(cellK).Value = aText;
            
            cellL=append('L',int2str(start));
            lefted =num2str(graycoprops(app.finalSkullImg).Homogeneity);
            aText=append(lefted);
            excelSheet.Range(cellL).Value = aText;
            
            glcm=graycomatrix(app.finalSkullImg);
            stats=GLCM_Features1(glcm,0);
            statsTable=struct2table(stats);
            statsArray=table2array(statsTable);
            display(statsTable.autoc);            
            
            cellM=append('M',int2str(start));
            lefted =num2str(statsTable.autoc);
            aText=append(lefted);
            excelSheet.Range(cellM).Value = aText;
            
            cellN=append('N',int2str(start));
            lefted =num2str(statsTable.cprom);
            aText=append(lefted);
            excelSheet.Range(cellN).Value = aText;
            
            cellO=append('O',int2str(start));
            lefted =num2str(statsTable.cshad);
            aText=append(lefted);
            excelSheet.Range(cellO).Value = aText;
            
            cellP=append('P',int2str(start));
            lefted =num2str(statsTable.dissi);
            aText=append(lefted);
            excelSheet.Range(cellP).Value = aText;
            
            cellQ=append('Q',int2str(start));
            lefted =num2str(statsTable.maxpr);
            aText=append(lefted);
            excelSheet.Range(cellQ).Value = aText;
            
            cellR=append('R',int2str(start));
            lefted =num2str(statsTable.sosvh);
            aText=append(lefted);
            excelSheet.Range(cellR).Value = aText;
            
            cellS=append('S',int2str(start));
            lefted =num2str(statsTable.savgh);
            aText=append(lefted);
            excelSheet.Range(cellS).Value = aText;
            
            cellT=append('T',int2str(start));
            lefted =num2str(statsTable.svarh);
            aText=append(lefted);
            excelSheet.Range(cellT).Value = aText;
            
            cellU=append('U',int2str(start));
            lefted =num2str(statsTable.senth);
            aText=append(lefted);
            excelSheet.Range(cellU).Value = aText;
            
            cellV=append('V',int2str(start));
            lefted =num2str(statsTable.dvarh);
            aText=append(lefted);
            excelSheet.Range(cellV).Value = aText;
            
            cellW=append('W',int2str(start));
            lefted =num2str(statsTable.denth);
            aText=append(lefted);
            excelSheet.Range(cellW).Value = aText;
            
            cellX=append('X',int2str(start));
            lefted =num2str(statsTable.inf1h);
            aText=append(lefted);
            excelSheet.Range(cellX).Value = aText;
            
            cellY=append('Y',int2str(start));
            lefted =num2str(statsTable.inf2h);
            aText=append(lefted);
            excelSheet.Range(cellY).Value = aText;
            
            cellZ=append('Z',int2str(start));
            lefted =num2str(statsTable.homom);
            aText=append(lefted);
            excelSheet.Range(cellZ).Value = aText;
            
            cellAA=append('AA',int2str(start));
            lefted =num2str(statsTable.indnc);
            aText=append(lefted);
            excelSheet.Range(cellAA).Value = aText;
            
            cellAB=append('AB',int2str(start));
            lefted =num2str(statsTable.idmnc);
            aText=append(lefted);
            excelSheet.Range(cellAB).Value = aText;
                        
            cellAC=append('AC',int2str(start));
            aText=append(num2str(app.group));
            excelSheet.Range(cellAC).Value = aText; 


            excelWorkbook.SaveAs('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMusertest.xlsx'); 
            excelWorkbook.Close();               
            excel.Quit();                         
            excel.delete();                      

            T= readtable('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMusertest.xlsx','ReadRowNames',true);
            writetable(T,'C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMusertest.csv','WriteRowNames',true);

            %hippocampus Manu GLCM  
%             excel = actxserver('Excel.Application');  
%             excelWorkbook = excel.Workbooks.Add(1);   
%             excelSheet = excel.ActiveSheet;          
%             
%             excelSheet.Range('A1').Value = "Mean";
%             excelSheet.Range('B1').Value = "Standard Deviation";
%             excelSheet.Range('C1').Value = "Variance";
%             excelSheet.Range('D1').Value = "Entropy";
%             excelSheet.Range('E1').Value = "Maximum G Intensity";
%             excelSheet.Range('F1').Value = "Minimum G Intensity";
%             excelSheet.Range('G1').Value = "Skewness";
%             excelSheet.Range('H1').Value = "Kurtosis";
%             excelSheet.Range('I1').Value = "Contrast";
%             excelSheet.Range('J1').Value = "Correlation";
%             excelSheet.Range('K1').Value = "Energy";
%             excelSheet.Range('L1').Value = "Homogenity";
%             excelSheet.Range('M1').Value = "AutoCorrelation";
%             excelSheet.Range('N1').Value = "Cluster Prominence";
%             excelSheet.Range('O1').Value = "Cluster Shade";
%             excelSheet.Range('P1').Value = "Dissimilarity";
%             excelSheet.Range('Q1').Value = "MaximumProbability";
%             excelSheet.Range('R1').Value = "Sum of sqaures";
%             excelSheet.Range('S1').Value = "Sum average";
%             excelSheet.Range('T1').Value = "Sum Variance";
%             excelSheet.Range('U1').Value = "Sum entropy ";
%             excelSheet.Range('V1').Value = "Difference variance";
%             excelSheet.Range('W1').Value = "Difference Entropy";
%             excelSheet.Range('X1').Value = "Information measure of correlation1";
%             excelSheet.Range('Y1').Value = "Information measure of correlation2";
%             excelSheet.Range('Z1').Value = "Inverse Difference";
%             excelSheet.Range('AA1').Value = "Inverse Difference Normalized";
%             excelSheet.Range('AB1').Value = "Inverse Difference Moment Normalized";
%             excelSheet.Range('AC1').Value = "Ground Truth Value";
%             
%             start=2;
%             cellA=append('A',int2str(start));
%             lefted = num2str(mean2(app.currentCluster(:)));
%             aText=append(lefted);
%             excelSheet.Range(cellA).Value = aText;
%             
%             cellB=append('B',int2str(start));
%             l1=double(app.currentCluster);
%             lefted =num2str(std(l1(:))); 
%             aText=append(lefted);
%             excelSheet.Range(cellB).Value = aText;
%                             
%             cellC=append('C',int2str(start));
%             l1=double(app.currentCluster);
%             lefted =num2str(var(l1(:)));
%             aText=append(lefted);
%             excelSheet.Range(cellC).Value = aText;
%             
%             cellD=append('D',int2str(start));
%             lefted = num2str(entropy(app.currentCluster));
%             aText=append(lefted);
%             excelSheet.Range(cellD).Value = aText;            
%             
%             cellE=append('E',int2str(start));
%             lefted = num2str(max(app.currentCluster(:)));
%             aText=append(lefted);
%             excelSheet.Range(cellE).Value = aText;
%             
%             cellF=append('F',int2str(start));
%             lefted = num2str(min(app.currentCluster(:)));
%             aText=append(lefted);
%             excelSheet.Range(cellF).Value = aText;
%             
%             cellG=append('G',int2str(start));
%             lefted = num2str(skewness(app.currentCluster(:)));
%             aText=append(lefted);
%             excelSheet.Range(cellG).Value = aText;
%             
%             cellH=append('H',int2str(start));
%             lefted = num2str(kurtosis(app.currentCluster(:)));
%             aText=append(lefted);
%             excelSheet.Range(cellH).Value = aText;
%             
%             cellI=append('I',int2str(start));
%             lefted =num2str(graycoprops(app.currentCluster).Contrast);
%             aText=append(lefted);
%             excelSheet.Range(cellI).Value = aText;
%             
%             cellJ=append('J',int2str(start));
%             lefted =num2str(graycoprops(app.currentCluster).Correlation);
%             aText=append(lefted);
%             excelSheet.Range(cellJ).Value = aText;            
%                         
%             cellK=append('K',int2str(start));
%             lefted =num2str(graycoprops(app.currentCluster).Energy);
%             aText=append(lefted);
%             excelSheet.Range(cellK).Value = aText;
%             
%             cellL=append('L',int2str(start));
%             lefted =num2str(graycoprops(app.currentCluster).Homogeneity);
%             aText=append(lefted);
%             excelSheet.Range(cellL).Value = aText;
%             
%             glcm=graycomatrix(app.currentCluster);
%             stats=GLCM_Features1(glcm,0);
%             statsTable=struct2table(stats);
%             statsArray=table2array(statsTable);
%             display(statsTable.autoc);            
%             
%             cellM=append('M',int2str(start));
%             lefted =num2str(statsTable.autoc);
%             aText=append(lefted);
%             excelSheet.Range(cellM).Value = aText;
%             
%             cellN=append('N',int2str(start));
%             lefted =num2str(statsTable.cprom);
%             aText=append(lefted);
%             excelSheet.Range(cellN).Value = aText;
%             
%             cellO=append('O',int2str(start));
%             lefted =num2str(statsTable.cshad);
%             aText=append(lefted);
%             excelSheet.Range(cellO).Value = aText;
%             
%             cellP=append('P',int2str(start));
%             lefted =num2str(statsTable.dissi);
%             aText=append(lefted);
%             excelSheet.Range(cellP).Value = aText;
%             
%             cellQ=append('Q',int2str(start));
%             lefted =num2str(statsTable.maxpr);
%             aText=append(lefted);
%             excelSheet.Range(cellQ).Value = aText;
%             
%             cellR=append('R',int2str(start));
%             lefted =num2str(statsTable.sosvh);
%             aText=append(lefted);
%             excelSheet.Range(cellR).Value = aText;
%             
%             cellS=append('S',int2str(start));
%             lefted =num2str(statsTable.savgh);
%             aText=append(lefted);
%             excelSheet.Range(cellS).Value = aText;
%             
%             cellT=append('T',int2str(start));
%             lefted =num2str(statsTable.svarh);
%             aText=append(lefted);
%             excelSheet.Range(cellT).Value = aText;
%             
%             cellU=append('U',int2str(start));
%             lefted =num2str(statsTable.senth);
%             aText=append(lefted);
%             excelSheet.Range(cellU).Value = aText;
%             
%             cellV=append('V',int2str(start));
%             lefted =num2str(statsTable.dvarh);
%             aText=append(lefted);
%             excelSheet.Range(cellV).Value = aText;
%             
%             cellW=append('W',int2str(start));
%             lefted =num2str(statsTable.denth);
%             aText=append(lefted);
%             excelSheet.Range(cellW).Value = aText;
%             
%             cellX=append('X',int2str(start));
%             lefted =num2str(statsTable.inf1h);
%             aText=append(lefted);
%             excelSheet.Range(cellX).Value = aText;
%             
%             cellY=append('Y',int2str(start));
%             lefted =num2str(statsTable.inf2h);
%             aText=append(lefted);
%             excelSheet.Range(cellY).Value = aText;
%             
%             cellZ=append('Z',int2str(start));
%             lefted =num2str(statsTable.homom);
%             aText=append(lefted);
%             excelSheet.Range(cellZ).Value = aText;
%             
%             cellAA=append('AA',int2str(start));
%             lefted =num2str(statsTable.indnc);
%             aText=append(lefted);
%             excelSheet.Range(cellAA).Value = aText;
%             
%             cellAB=append('AB',int2str(start));
%             lefted =num2str(statsTable.idmnc);
%             aText=append(lefted);
%             excelSheet.Range(cellAB).Value = aText;
%             
%             cellAC=append('AC',int2str(start));
%             aText=append(num2str(app.group));
%             excelSheet.Range(cellAC).Value = aText;
% 
%             excelWorkbook.SaveAs('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\hippoManuGLCMusertest.xlsx'); 
%             excelWorkbook.Close();                 
%             excel.Quit();                         
%             excel.delete();                       
% 
%             T= readtable('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\hippoManuGLCMusertest.xlsx','ReadRowNames',true);
%             writetable(T,'C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\hippoManuGLCMusertest.csv','WriteRowNames',true);

            %bin GLCM                                                                                   
            excel = actxserver('Excel.Application');  
            excelWorkbook = excel.Workbooks.Add(1);   
            excelSheet = excel.ActiveSheet;          
            
            excelSheet.Range('A1').Value = "Mean";
            excelSheet.Range('B1').Value = "Standard Deviation";
            excelSheet.Range('C1').Value = "Variance";
            excelSheet.Range('D1').Value = "Entropy";
            excelSheet.Range('E1').Value = "Maximum G Intensity";
            excelSheet.Range('F1').Value = "Minimum G Intensity";
            excelSheet.Range('G1').Value = "Skewness";
            excelSheet.Range('H1').Value = "Kurtosis";
            excelSheet.Range('I1').Value = "Contrast";
            excelSheet.Range('J1').Value = "Correlation";
            excelSheet.Range('K1').Value = "Energy";
            excelSheet.Range('L1').Value = "Homogenity";
            excelSheet.Range('M1').Value = "AutoCorrelation";
            excelSheet.Range('N1').Value = "Cluster Prominence";
            excelSheet.Range('O1').Value = "Cluster Shade";
            excelSheet.Range('P1').Value = "Dissimilarity";
            excelSheet.Range('Q1').Value = "MaximumProbability";
            excelSheet.Range('R1').Value = "Sum of sqaures";
            excelSheet.Range('S1').Value = "Sum average";
            excelSheet.Range('T1').Value = "Sum Variance";
            excelSheet.Range('U1').Value = "Sum entropy ";
            excelSheet.Range('V1').Value = "Difference variance";
            excelSheet.Range('W1').Value = "Difference Entropy";
            excelSheet.Range('X1').Value = "Information measure of correlation1";
            excelSheet.Range('Y1').Value = "Information measure of correlation2";
            excelSheet.Range('Z1').Value = "Inverse Difference";
            excelSheet.Range('AA1').Value = "Inverse Difference Normalized";
            excelSheet.Range('AB1').Value = "Inverse Difference Moment Normalized";
            excelSheet.Range('AC1').Value = "Ground Truth Value";
            
            start=2;

            cellA=append('A',int2str(start));
            lefted = num2str(mean2(app.connImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellA).Value = aText;
            
            cellB=append('B',int2str(start));
            l1=double(app.connImg);
            lefted =num2str(std(l1(:))); 
            aText=append(lefted);
            excelSheet.Range(cellB).Value = aText;
                            
            cellC=append('C',int2str(start));
            l1=double(app.connImg);
            lefted =num2str(var(l1(:)));
            aText=append(lefted);
            excelSheet.Range(cellC).Value = aText;
            
            cellD=append('D',int2str(start));
            lefted = num2str(entropy(app.connImg));
            aText=append(lefted);
            excelSheet.Range(cellD).Value = aText;           
            
            cellE=append('E',int2str(start));
            lefted = num2str(max(app.connImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellE).Value = aText;
            
            cellF=append('F',int2str(start));
            lefted = num2str(min(app.connImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellF).Value = aText;
            
            cellG=append('G',int2str(start));
            lefted = num2str(skewness(app.connImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellG).Value = aText;
            
            cellH=append('H',int2str(start));
            lefted = num2str(kurtosis(app.connImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellH).Value = aText;
            
            cellI=append('I',int2str(start));
            lefted =num2str(graycoprops(app.connImg).Contrast);
            aText=append(lefted);
            excelSheet.Range(cellI).Value = aText;
            
            cellJ=append('J',int2str(start));
            lefted =num2str(graycoprops(app.connImg).Correlation);
            aText=append(lefted);
            excelSheet.Range(cellJ).Value = aText;            
                        
            cellK=append('K',int2str(start));
            lefted =num2str(graycoprops(app.connImg).Energy);
            aText=append(lefted);
            excelSheet.Range(cellK).Value = aText;
            
            cellL=append('L',int2str(start));
            lefted =num2str(graycoprops(app.connImg).Homogeneity);
            aText=append(lefted);
            excelSheet.Range(cellL).Value = aText;
            
            glcm=graycomatrix(app.connImg);
            stats=GLCM_Features1(glcm,0);
            statsTable=struct2table(stats);
            statsArray=table2array(statsTable);
            display(statsTable.autoc);            
            
            cellM=append('M',int2str(start));
            lefted =num2str(statsTable.autoc);
            aText=append(lefted);
            excelSheet.Range(cellM).Value = aText;
            
            cellN=append('N',int2str(start));
            lefted =num2str(statsTable.cprom);
            aText=append(lefted);
            excelSheet.Range(cellN).Value = aText;
            
            cellO=append('O',int2str(start));
            lefted =num2str(statsTable.cshad);
            aText=append(lefted);
            excelSheet.Range(cellO).Value = aText;
            
            cellP=append('P',int2str(start));
            lefted =num2str(statsTable.dissi);
            aText=append(lefted);
            excelSheet.Range(cellP).Value = aText;
            
            cellQ=append('Q',int2str(start));
            lefted =num2str(statsTable.maxpr);
            aText=append(lefted);
            excelSheet.Range(cellQ).Value = aText;
            
            cellR=append('R',int2str(start));
            lefted =num2str(statsTable.sosvh);
            aText=append(lefted);
            excelSheet.Range(cellR).Value = aText;
            
            cellS=append('S',int2str(start));
            lefted =num2str(statsTable.savgh);
            aText=append(lefted);
            excelSheet.Range(cellS).Value = aText;
            
            cellT=append('T',int2str(start));
            lefted =num2str(statsTable.svarh);
            aText=append(lefted);
            excelSheet.Range(cellT).Value = aText;
            
            cellU=append('U',int2str(start));
            lefted =num2str(statsTable.senth);
            aText=append(lefted);
            excelSheet.Range(cellU).Value = aText;
            
            cellV=append('V',int2str(start));
            lefted =num2str(statsTable.dvarh);
            aText=append(lefted);
            excelSheet.Range(cellV).Value = aText;
            
            cellW=append('W',int2str(start));
            lefted =num2str(statsTable.denth);
            aText=append(lefted);
            excelSheet.Range(cellW).Value = aText;
            
            cellX=append('X',int2str(start));
            lefted =num2str(statsTable.inf1h);
            aText=append(lefted);
            excelSheet.Range(cellX).Value = aText;
            
            cellY=append('Y',int2str(start));
            lefted =num2str(statsTable.inf2h);
            aText=append(lefted);
            excelSheet.Range(cellY).Value = aText;
            
            cellZ=append('Z',int2str(start));
            lefted =num2str(statsTable.homom);
            aText=append(lefted);
            excelSheet.Range(cellZ).Value = aText;
            
            cellAA=append('AA',int2str(start));
            lefted =num2str(statsTable.indnc);
            aText=append(lefted);
            excelSheet.Range(cellAA).Value = aText;
            
            cellAB=append('AB',int2str(start));
            lefted =num2str(statsTable.idmnc);
            aText=append(lefted);
            excelSheet.Range(cellAB).Value = aText;
                        
            cellAC=append('AC',int2str(start));
            aText=append(num2str(app.group));
            excelSheet.Range(cellAC).Value = aText; 


            excelWorkbook.SaveAs('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ConnectedGLCMusertest.xlsx'); 
            excelWorkbook.Close();               
            excel.Quit();                         
            excel.delete();                       

            T= readtable('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ConnectedGLCMusertest.xlsx','ReadRowNames',true);
            writetable(T,'C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ConnectedGLCMusertest.csv','WriteRowNames',true);


            %skull shape
            excel = actxserver('Excel.Application');  
            excelWorkbook = excel.Workbooks.Add(1);   
            excelSheet = excel.ActiveSheet;           
            
            excelSheet.Range('A1').Value = "Area";
            excelSheet.Range('B1').Value = "Bounding Box";
            excelSheet.Range('C1').Value = "Convex Area";
            excelSheet.Range('D1').Value = "Eccentricity";
            excelSheet.Range('E1').Value = "EquivDiameter";
            excelSheet.Range('F1').Value = "EulerNumber";
            excelSheet.Range('G1').Value = "FilledArea";
            excelSheet.Range('H1').Value = "MajorAxisLength";
            excelSheet.Range('I1').Value = "MinorAxisLength";
            excelSheet.Range('J1').Value = "Orientation";
            excelSheet.Range('K1').Value = "Perimeter";
            excelSheet.Range('L1').Value = "Ground Truth Value";

            start=2;

            cellA=append('A',int2str(start));
            aText=append(num2str(bwarea(app.finalSkullImg)));
            excelSheet.Range(cellA).Value = aText;
        
            t=regionprops(app.finalSkullImg,'BoundingBox');
            cellB=append('B',int2str(start));
            aText=append(num2str(mean([t.BoundingBox])));
            excelSheet.Range(cellB).Value = aText;      
                              
            t=regionprops(app.finalSkullImg,'ConvexArea');
            cellC=append('C',int2str(start));
            aText=append(num2str(mean([t.ConvexArea])));
            excelSheet.Range(cellC).Value = aText;
        
            t=regionprops(app.finalSkullImg,'Eccentricity');
            cellD=append('D',int2str(start));
            aText=append(num2str(mean([t.Eccentricity])));
            excelSheet.Range(cellD).Value = aText;
        
            t=regionprops(app.finalSkullImg,'EquivDiameter');
            cellE=append('E',int2str(start));
            aText=append(num2str(mean([t.EquivDiameter])));
            excelSheet.Range(cellE).Value = aText;
                
            cellF=append('F',int2str(start));
            temp=num2str(bweuler(app.finalSkullImg));
            aText=append(temp);
            excelSheet.Range(cellF).Value = aText;

            t=regionprops(app.finalSkullImg,'FilledArea');
            cellG=append('G',int2str(start));
            aText=append(num2str(mean([t.FilledArea])));
            excelSheet.Range(cellG).Value = aText;        
                
            t=regionprops(app.finalSkullImg,'MajorAxisLength');
            cellH=append('H',int2str(start));
            aText=append(num2str(mean([t.MajorAxisLength])));
            excelSheet.Range(cellH).Value = aText;
        
            t=regionprops(app.finalSkullImg,'MinorAxisLength');
            cellI=append('I',int2str(start));
            aText=append(num2str(mean([t.MinorAxisLength])));
            excelSheet.Range(cellI).Value = aText;
            
            t=regionprops(app.finalSkullImg,'Orientation');
            cellJ=append('J',int2str(start));
            aText=append(num2str(mean([t.Orientation])));
            excelSheet.Range(cellJ).Value = aText;
        
            t=regionprops(app.finalSkullImg,'Perimeter');
            cellK=append('K',int2str(start));
            aText=append(num2str(mean([t.Perimeter])));
            excelSheet.Range(cellK).Value = aText;       
                              
            cellL=append('L',int2str(start));               
            aText=append(num2str(app.group));
            excelSheet.Range(cellL).Value = aText;

            excelWorkbook.SaveAs('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapeusertest.xlsx');             
            excelWorkbook.Close();                 
            excel.Quit();                         
            excel.delete();                       

            T= readtable('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapeusertest.xlsx','ReadRowNames',true);
            writetable(T,'C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapeusertest.csv','WriteRowNames',true);


            %hippo Manu shape
            excel = actxserver('Excel.Application');  
            excelWorkbook = excel.Workbooks.Add(1);  
            excelSheet = excel.ActiveSheet;           
                
            excelSheet.Range('A1').Value = "Area";
            excelSheet.Range('B1').Value = "Bounding Box";
            excelSheet.Range('C1').Value = "Convex Area";
            excelSheet.Range('D1').Value = "Eccentricity";
            excelSheet.Range('E1').Value = "EquivDiameter";
            excelSheet.Range('F1').Value = "EulerNumber";
            excelSheet.Range('G1').Value = "FilledArea";
            excelSheet.Range('H1').Value = "MajorAxisLength";
            excelSheet.Range('I1').Value = "MinorAxisLength";
            excelSheet.Range('J1').Value = "Orientation";
            excelSheet.Range('K1').Value = "Perimeter";
            excelSheet.Range('L1').Value = "Ground Truth Value";

            start=2;
    
            cellA=append('A',int2str(start));
            aText=append(num2str(bwarea(app.currentCluster)));
            excelSheet.Range(cellA).Value = aText;
        
            t=regionprops(app.currentCluster,'BoundingBox');
            cellB=append('B',int2str(start));
            aText=append(num2str(mean([t.BoundingBox])));
            excelSheet.Range(cellB).Value = aText;
        
            t=regionprops(app.currentCluster,'ConvexArea');
            cellC=append('C',int2str(start));
            aText=append(num2str(mean([t.ConvexArea])));
            excelSheet.Range(cellC).Value = aText;
        
            t=regionprops(app.currentCluster,'Eccentricity');
            cellD=append('D',int2str(start));
            aText=append(num2str(mean([t.Eccentricity])));
            excelSheet.Range(cellD).Value = aText;
        
            t=regionprops(app.currentCluster,'EquivDiameter');
            cellE=append('E',int2str(start));
            aText=append(num2str(mean([t.EquivDiameter])));
            excelSheet.Range(cellE).Value = aText;
                
            cellF=append('F',int2str(start));
            temp=num2str(bweuler(app.currentCluster));
            aText=append(temp);
            excelSheet.Range(cellF).Value = aText;
             
            t=regionprops(app.currentCluster,'FilledArea');
            cellG=append('G',int2str(start));
            aText=append(num2str(mean([t.FilledArea])));
            excelSheet.Range(cellG).Value = aText;
                   
            t=regionprops(app.currentCluster,'MajorAxisLength');
            cellH=append('H',int2str(start));
            aText=append(num2str(mean([t.MajorAxisLength])));
            excelSheet.Range(cellH).Value = aText;
        
            t=regionprops(app.currentCluster,'MinorAxisLength');
            cellI=append('I',int2str(start));
            aText=append(num2str(mean([t.MinorAxisLength])));
            excelSheet.Range(cellI).Value = aText;
            
            t=regionprops(app.currentCluster,'Orientation');
            cellJ=append('J',int2str(start));
            aText=append(num2str(mean([t.Orientation])));
            excelSheet.Range(cellJ).Value = aText;
        
            t=regionprops(app.currentCluster,'Perimeter');
            cellK=append('K',int2str(start));
            aText=append(num2str(mean([t.Perimeter])));
            excelSheet.Range(cellK).Value = aText;
            
            cellL=append('L',int2str(start));
            aText=append(num2str(app.group));
            excelSheet.Range(cellL).Value = aText;
          
            excelWorkbook.SaveAs('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\hippoManuShapeusertest.xlsx');            
            excelWorkbook.Close();                 
            excel.Quit();                         
            excel.delete(); 

            T= readtable('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\hippoManuShapeusertest.xlsx','ReadRowNames',true);
            writetable(T,'C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\hippoManuShapeusertest.csv','WriteRowNames',true);          

            %Connectome shape
            excel = actxserver('Excel.Application');  
            excelWorkbook = excel.Workbooks.Add(1);   
            excelSheet = excel.ActiveSheet;           
            
            excelSheet.Range('A1').Value = "Area";
            excelSheet.Range('B1').Value = "Bounding Box";
            excelSheet.Range('C1').Value = "Convex Area";
            excelSheet.Range('D1').Value = "Eccentricity";
            excelSheet.Range('E1').Value = "EquivDiameter";
            excelSheet.Range('F1').Value = "EulerNumber";
            excelSheet.Range('G1').Value = "FilledArea";
            excelSheet.Range('H1').Value = "MajorAxisLength";
            excelSheet.Range('I1').Value = "MinorAxisLength";
            excelSheet.Range('J1').Value = "Orientation";
            excelSheet.Range('K1').Value = "Perimeter";
            excelSheet.Range('L1').Value = "Ground Truth Value";

            start=2;

            cellA=append('A',int2str(start));
            aText=append(num2str(bwarea(app.connImg)));
            excelSheet.Range(cellA).Value = aText;
        
            t=regionprops(app.connImg,'BoundingBox');
            cellB=append('B',int2str(start));
            aText=append(num2str(mean([t.BoundingBox])));
            excelSheet.Range(cellB).Value = aText;      
                              
            t=regionprops(app.connImg,'ConvexArea');
            cellC=append('C',int2str(start));
            aText=append(num2str(mean([t.ConvexArea])));
            excelSheet.Range(cellC).Value = aText;
        
            t=regionprops(app.connImg,'Eccentricity');
            cellD=append('D',int2str(start));
            aText=append(num2str(mean([t.Eccentricity])));
            excelSheet.Range(cellD).Value = aText;
        
            t=regionprops(app.connImg,'EquivDiameter');
            cellE=append('E',int2str(start));
            aText=append(num2str(mean([t.EquivDiameter])));
            excelSheet.Range(cellE).Value = aText;
                
            cellF=append('F',int2str(start));
            temp=num2str(bweuler(app.connImg));
            aText=append(temp);
            excelSheet.Range(cellF).Value = aText;

            t=regionprops(app.connImg,'FilledArea');
            cellG=append('G',int2str(start));
            aText=append(num2str(mean([t.FilledArea])));
            excelSheet.Range(cellG).Value = aText;        
                
            t=regionprops(app.connImg,'MajorAxisLength');
            cellH=append('H',int2str(start));
            aText=append(num2str(mean([t.MajorAxisLength])));
            excelSheet.Range(cellH).Value = aText;
        
            t=regionprops(app.connImg,'MinorAxisLength');
            cellI=append('I',int2str(start));
            aText=append(num2str(mean([t.MinorAxisLength])));
            excelSheet.Range(cellI).Value = aText;
            
            t=regionprops(app.connImg,'Orientation');
            cellJ=append('J',int2str(start));
            aText=append(num2str(mean([t.Orientation])));
            excelSheet.Range(cellJ).Value = aText;
        
            t=regionprops(app.connImg,'Perimeter');
            cellK=append('K',int2str(start));
            aText=append(num2str(mean([t.Perimeter])));
            excelSheet.Range(cellK).Value = aText;       
                              
            cellL=append('L',int2str(start));               
            aText=append(num2str(app.group));
            excelSheet.Range(cellL).Value = aText;

            excelWorkbook.SaveAs('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ConnectedShapeusertest.xlsx');             
            excelWorkbook.Close();                 
            excel.Quit();                         
            excel.delete();                       

            T= readtable('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ConnectedShapeusertest.xlsx','ReadRowNames',true);
            writetable(T,'C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ConnectedShapeusertest.csv','WriteRowNames',true);


            %roi shape
            excel = actxserver('Excel.Application');  
            excelWorkbook = excel.Workbooks.Add(1);   
            excelSheet = excel.ActiveSheet;           
            
            excelSheet.Range('A1').Value = "Area";
            excelSheet.Range('B1').Value = "Bounding Box";
            excelSheet.Range('C1').Value = "Convex Area";
            excelSheet.Range('D1').Value = "Eccentricity";
            excelSheet.Range('E1').Value = "EquivDiameter";
            excelSheet.Range('F1').Value = "EulerNumber";
            excelSheet.Range('G1').Value = "FilledArea";
            excelSheet.Range('H1').Value = "MajorAxisLength";
            excelSheet.Range('I1').Value = "MinorAxisLength";
            excelSheet.Range('J1').Value = "Orientation";
            excelSheet.Range('K1').Value = "Perimeter";
            excelSheet.Range('L1').Value = "Ground Truth Value";

            start=2;

            cellA=append('A',int2str(start));
            aText=append(num2str(bwarea(app.roiImg)));
            excelSheet.Range(cellA).Value = aText;
        
            t=regionprops(app.roiImg,'BoundingBox');
            cellB=append('B',int2str(start));
            aText=append(num2str(mean([t.BoundingBox])));
            excelSheet.Range(cellB).Value = aText;      
                              
            t=regionprops(app.roiImg,'ConvexArea');
            cellC=append('C',int2str(start));
            aText=append(num2str(mean([t.ConvexArea])));
            excelSheet.Range(cellC).Value = aText;
        
            t=regionprops(app.roiImg,'Eccentricity');
            cellD=append('D',int2str(start));
            aText=append(num2str(mean([t.Eccentricity])));
            excelSheet.Range(cellD).Value = aText;
        
            t=regionprops(app.roiImg,'EquivDiameter');
            cellE=append('E',int2str(start));
            aText=append(num2str(mean([t.EquivDiameter])));
            excelSheet.Range(cellE).Value = aText;
                
            cellF=append('F',int2str(start));
            temp=num2str(bweuler(app.roiImg));
            aText=append(temp);
            excelSheet.Range(cellF).Value = aText;

            t=regionprops(app.roiImg,'FilledArea');
            cellG=append('G',int2str(start));
            aText=append(num2str(mean([t.FilledArea])));
            excelSheet.Range(cellG).Value = aText;        
                
            t=regionprops(app.roiImg,'MajorAxisLength');
            cellH=append('H',int2str(start));
            aText=append(num2str(mean([t.MajorAxisLength])));
            excelSheet.Range(cellH).Value = aText;
        
            t=regionprops(app.roiImg,'MinorAxisLength');
            cellI=append('I',int2str(start));
            aText=append(num2str(mean([t.MinorAxisLength])));
            excelSheet.Range(cellI).Value = aText;
            
            t=regionprops(app.roiImg,'Orientation');
            cellJ=append('J',int2str(start));
            aText=append(num2str(mean([t.Orientation])));
            excelSheet.Range(cellJ).Value = aText;
        
            t=regionprops(app.roiImg,'Perimeter');
            cellK=append('K',int2str(start));
            aText=append(num2str(mean([t.Perimeter])));
            excelSheet.Range(cellK).Value = aText;       
                              
            cellL=append('L',int2str(start));               
            aText=append(num2str(app.group));
            excelSheet.Range(cellL).Value = aText;

            excelWorkbook.SaveAs('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\roiShapeusertest.xlsx');             
            excelWorkbook.Close();                 
            excel.Quit();                         
            excel.delete();                       

            T= readtable('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\roiShapeusertest.xlsx','ReadRowNames',true);
            writetable(T,'C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\roiShapeusertest.csv','WriteRowNames',true);


            %roi GLCM                                                                                   
            excel = actxserver('Excel.Application');  
            excelWorkbook = excel.Workbooks.Add(1);   
            excelSheet = excel.ActiveSheet;          
            
            excelSheet.Range('A1').Value = "Mean";
            excelSheet.Range('B1').Value = "Standard Deviation";
            excelSheet.Range('C1').Value = "Variance";
            excelSheet.Range('D1').Value = "Entropy";
            excelSheet.Range('E1').Value = "Maximum G Intensity";
            excelSheet.Range('F1').Value = "Minimum G Intensity";
            excelSheet.Range('G1').Value = "Skewness";
            excelSheet.Range('H1').Value = "Kurtosis";
            excelSheet.Range('I1').Value = "Contrast";
            excelSheet.Range('J1').Value = "Correlation";
            excelSheet.Range('K1').Value = "Energy";
            excelSheet.Range('L1').Value = "Homogenity";
            excelSheet.Range('M1').Value = "AutoCorrelation";
            excelSheet.Range('N1').Value = "Cluster Prominence";
            excelSheet.Range('O1').Value = "Cluster Shade";
            excelSheet.Range('P1').Value = "Dissimilarity";
            excelSheet.Range('Q1').Value = "MaximumProbability";
            excelSheet.Range('R1').Value = "Sum of sqaures";
            excelSheet.Range('S1').Value = "Sum average";
            excelSheet.Range('T1').Value = "Sum Variance";
            excelSheet.Range('U1').Value = "Sum entropy ";
            excelSheet.Range('V1').Value = "Difference variance";
            excelSheet.Range('W1').Value = "Difference Entropy";
            excelSheet.Range('X1').Value = "Information measure of correlation1";
            excelSheet.Range('Y1').Value = "Information measure of correlation2";
            excelSheet.Range('Z1').Value = "Inverse Difference";
            excelSheet.Range('AA1').Value = "Inverse Difference Normalized";
            excelSheet.Range('AB1').Value = "Inverse Difference Moment Normalized";
            excelSheet.Range('AC1').Value = "Ground Truth Value";
            
            start=2;

            cellA=append('A',int2str(start));
            lefted = num2str(mean2(app.roiImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellA).Value = aText;
            
            cellB=append('B',int2str(start));
            l1=double(app.roiImg);
            lefted =num2str(std(l1(:))); 
            aText=append(lefted);
            excelSheet.Range(cellB).Value = aText;
                            
            cellC=append('C',int2str(start));
            l1=double(app.roiImg);
            lefted =num2str(var(l1(:)));
            aText=append(lefted);
            excelSheet.Range(cellC).Value = aText;
            
            cellD=append('D',int2str(start));
            lefted = num2str(entropy(app.roiImg));
            aText=append(lefted);
            excelSheet.Range(cellD).Value = aText;           
            
            cellE=append('E',int2str(start));
            lefted = num2str(max(app.roiImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellE).Value = aText;
            
            cellF=append('F',int2str(start));
            lefted = num2str(min(app.roiImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellF).Value = aText;
            
            cellG=append('G',int2str(start));
            lefted = num2str(skewness(app.roiImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellG).Value = aText;
            
            cellH=append('H',int2str(start));
            lefted = num2str(kurtosis(app.roiImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellH).Value = aText;
            
            cellI=append('I',int2str(start));
            lefted =num2str(graycoprops(app.roiImg).Contrast);
            aText=append(lefted);
            excelSheet.Range(cellI).Value = aText;
            
            cellJ=append('J',int2str(start));
            lefted =num2str(graycoprops(app.roiImg).Correlation);
            aText=append(lefted);
            excelSheet.Range(cellJ).Value = aText;            
                        
            cellK=append('K',int2str(start));
            lefted =num2str(graycoprops(app.roiImg).Energy);
            aText=append(lefted);
            excelSheet.Range(cellK).Value = aText;
            
            cellL=append('L',int2str(start));
            lefted =num2str(graycoprops(app.roiImg).Homogeneity);
            aText=append(lefted);
            excelSheet.Range(cellL).Value = aText;
            
            glcm=graycomatrix(app.roiImg);
            stats=GLCM_Features1(glcm,0);
            statsTable=struct2table(stats);
            statsArray=table2array(statsTable);
            display(statsTable.autoc);            
            
            cellM=append('M',int2str(start));
            lefted =num2str(statsTable.autoc);
            aText=append(lefted);
            excelSheet.Range(cellM).Value = aText;
            
            cellN=append('N',int2str(start));
            lefted =num2str(statsTable.cprom);
            aText=append(lefted);
            excelSheet.Range(cellN).Value = aText;
            
            cellO=append('O',int2str(start));
            lefted =num2str(statsTable.cshad);
            aText=append(lefted);
            excelSheet.Range(cellO).Value = aText;
            
            cellP=append('P',int2str(start));
            lefted =num2str(statsTable.dissi);
            aText=append(lefted);
            excelSheet.Range(cellP).Value = aText;
            
            cellQ=append('Q',int2str(start));
            lefted =num2str(statsTable.maxpr);
            aText=append(lefted);
            excelSheet.Range(cellQ).Value = aText;
            
            cellR=append('R',int2str(start));
            lefted =num2str(statsTable.sosvh);
            aText=append(lefted);
            excelSheet.Range(cellR).Value = aText;
            
            cellS=append('S',int2str(start));
            lefted =num2str(statsTable.savgh);
            aText=append(lefted);
            excelSheet.Range(cellS).Value = aText;
            
            cellT=append('T',int2str(start));
            lefted =num2str(statsTable.svarh);
            aText=append(lefted);
            excelSheet.Range(cellT).Value = aText;
            
            cellU=append('U',int2str(start));
            lefted =num2str(statsTable.senth);
            aText=append(lefted);
            excelSheet.Range(cellU).Value = aText;
            
            cellV=append('V',int2str(start));
            lefted =num2str(statsTable.dvarh);
            aText=append(lefted);
            excelSheet.Range(cellV).Value = aText;
            
            cellW=append('W',int2str(start));
            lefted =num2str(statsTable.denth);
            aText=append(lefted);
            excelSheet.Range(cellW).Value = aText;
            
            cellX=append('X',int2str(start));
            lefted =num2str(statsTable.inf1h);
            aText=append(lefted);
            excelSheet.Range(cellX).Value = aText;
            
            cellY=append('Y',int2str(start));
            lefted =num2str(statsTable.inf2h);
            aText=append(lefted);
            excelSheet.Range(cellY).Value = aText;
            
            cellZ=append('Z',int2str(start));
            lefted =num2str(statsTable.homom);
            aText=append(lefted);
            excelSheet.Range(cellZ).Value = aText;
            
            cellAA=append('AA',int2str(start));
            lefted =num2str(statsTable.indnc);
            aText=append(lefted);
            excelSheet.Range(cellAA).Value = aText;
            
            cellAB=append('AB',int2str(start));
            lefted =num2str(statsTable.idmnc);
            aText=append(lefted);
            excelSheet.Range(cellAB).Value = aText;
                        
            cellAC=append('AC',int2str(start));
            aText=append(num2str(app.group));
            excelSheet.Range(cellAC).Value = aText; 


            excelWorkbook.SaveAs('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\roiGLCMusertest.xlsx'); 
            excelWorkbook.Close();               
            excel.Quit();                         
            excel.delete();                       

            T= readtable('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\roiGLCMusertest.xlsx','ReadRowNames',true);
            writetable(T,'C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\roiGLCMusertest.csv','WriteRowNames',true);

        end

        % Button pushed function: SKULLCLASSIFYKNNButton
        function knnClassify(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtrain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.lazy.IBk');  
            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtrain.csv');
            v3 = String('-T');
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtest.csv');      
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\nPerformace of KNN for Skull Stripped Image GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtrain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of KNN for Skull Stripped Image GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = utestset.numInstances;

            classes = zeros(numInst,1);
            pred='';
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nSkull Stripped Image (GLCM..Features): %s\n\n\n\n",pred,'Normal');
                 else
                     pred=sprintf("%s\n\nSkull Stripped Image (GLCM..Features):%s\n\n\n",pred,'Abnormal');
                 end
            end

            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetrain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapeusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);
            

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetrain.csv');
            v3 = String('-T');     
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetest.csv');       
            options = cat(1,v1,v2,v3,v4);

            classifier = javaObject('weka.classifiers.lazy.IBk');

            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\n\nPerformace of KNN for Skull Stripped Image Shape Features FOR TEST DATASET %s\n\n",result,temp);  

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetrain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of KNN for Skull Stripped Image Shape Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);

            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result;  
            
            numInst = utestset.numInstances;
            
            classes = zeros(numInst,1);
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nSkull Stripped Image (Shape Features): %s\n\n\n\n",pred,'Normal');
                 else
                     pred=sprintf("%s\n\nSkull Stripped Image (Shape Features): %s\n\n\n\n",pred,'Abnormal');
                 end
            end
            app.TESTDATARESULTTextArea.Value=pred;  
        end

        % Button pushed function: SKULLCLASSIFYDTButton
        function dtClassify(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtrain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.trees.J48');  
            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtrain.csv');
            v3 = String('-T');
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtest.csv');      
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\nPerformace of Decision Tree for Skull Stripped Image GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtrain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of Decision Tree for Skull Stripped Image GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = utestset.numInstances;

            classes = zeros(numInst,1);
            pred=''
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nSkull Stripped Image (GLCM..Features): %s\n\n\n\n",pred,'Normal')
                 else
                     pred=sprintf("%s\n\nSkull Stripped Image (GLCM..Features):%s\n\n\n",pred,'Abnormal')
                 end
            end

            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetrain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapeusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);
            

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetrain.csv');
            v3 = String('-T');     
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetest.csv');       
            options = cat(1,v1,v2,v3,v4);

            classifier = javaObject('weka.classifiers.trees.J48');

            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\n\nPerformace of Decision Tree for Skull Stripped Image Shape Features FOR TEST DATASET %s\n\n",result,temp);  

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetrain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of Decision Tree for Skull Stripped Image Shape Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);

            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result;  
            
            numInst = utestset.numInstances;
            
            classes = zeros(numInst,1);
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nSkull Stripped Image (Shape Features): %s\n\n\n\n",pred,'Normal');
                 else
                     pred=sprintf("%s\n\nSkull Stripped Image (Shape Features): %s\n\n\n\n",pred,'Abnormal');
                 end
            end
            app.TESTDATARESULTTextArea.Value=pred;  
        end

        % Button pushed function: ROIKNNButton
        function roiKNN(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTrain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\roiGLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.lazy.IBk');  
            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTrain.csv');
            v3 = String('-T');
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTest.csv');      
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\nPerformace of KNN for Image after binarizing brighter pixels GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_GLCM.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of KNN for Image after binarizing brighter pixels GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = utestset.numInstances;

            classes = zeros(numInst,1);
            pred='';
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (GLCM..Features): %s\n\n\n\n",pred,'Normal');
                 else
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (GLCM..Features):%s\n\n\n",pred,'Abnormal');
                 end
            end

            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTrain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);            
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\roiShapeusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);
            

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTrain.csv');
            v3 = String('-T');     
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTest.csv');       
            options = cat(1,v1,v2,v3,v4);

            classifier = javaObject('weka.classifiers.lazy.IBk');

            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\n\nPerformace of KNN for Image after binarizing brighter pixels Shape Features FOR TEST DATASET %s\n\n",result,temp);  

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTrain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of KNN for Image after binarizing brighter pixels Shape Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);

            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result;  
            
            numInst = utestset.numInstances;
            
            classes = zeros(numInst,1);
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (Shape Features): %s\n\n\n\n",pred,'Normal');
                 else
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (Shape Features): %s\n\n\n\n",pred,'Abnormal');
                 end
            end
            app.TESTDATARESULTTextArea.Value=pred;  
        end

        % Button pushed function: ROIDTButton
        function roiDt(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTrain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\roiGLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.trees.J48');  
            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTrain.csv');
            v3 = String('-T');
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTest.csv');      
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\nPerformace of Decision Tree for Skull Stripped Image GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTrain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of Decision Tree for Skull Stripped Image GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = utestset.numInstances;

            classes = zeros(numInst,1);
            pred=''
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nSkull Stripped Image (GLCM..Features): %s\n\n\n\n",pred,'Normal')
                 else
                     pred=sprintf("%s\n\nSkull Stripped Image (GLCM..Features):%s\n\n\n",pred,'Abnormal')
                 end
            end

            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTrain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);            
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\roiShapeusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);
            

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTrain.csv');
            v3 = String('-T');     
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTest.csv');       
            options = cat(1,v1,v2,v3,v4);

            classifier = javaObject('weka.classifiers.trees.J48');

            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\n\nPerformace of Decision Tree for Skull Stripped Image Shape Features FOR TEST DATASET %s\n\n",result,temp);  

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTrain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of Decision Tree for Skull Stripped Image Shape Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);

            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result;  
            
            numInst = utestset.numInstances;
            
            classes = zeros(numInst,1);
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nSkull Stripped Image (Shape Features): %s\n\n\n\n",pred,'Normal');
                 else
                     pred=sprintf("%s\n\nSkull Stripped Image (Shape Features): %s\n\n\n\n",pred,'Abnormal');
                 end
            end
            app.TESTDATARESULTTextArea.Value=pred;
        end

        % Button pushed function: ROIRFButton
        function roiRf(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTrain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\roiGLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.trees.RandomForest');  
            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTrain.csv');
            v3 = String('-T');
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTest.csv');      
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\nPerformace of Random Forest for Skull Stripped Image GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTrain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of Random Forest for Skull Stripped Image GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = testset.numInstances;

            classes = zeros(numInst,1);
            pred=''
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(testset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nSkull Stripped Image (GLCM..Features): %s\n\n\n\n",pred,'Normal')
                 else
                     pred=sprintf("%s\n\nSkull Stripped Image (GLCM..Features):%s\n\n\n",pred,'Abnormal')
                 end
            end

            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTrain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\roiShapeusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);
            

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTrain.csv');
            v3 = String('-T');     
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTest.csv');       
            options = cat(1,v1,v2,v3,v4);

            classifier = javaObject('weka.classifiers.trees.RandomForest');

            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\n\nPerformace of Random Forest for Skull Stripped Image Shape Features FOR TEST DATASET %s\n\n",result,temp);  

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTrain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of Random Forest for Skull Stripped Image Shape Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);

            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result;  
            
            numInst = testset.numInstances;
            
            classes = zeros(numInst,1);
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(testset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nROI Image (Shape Features): %s\n\n\n\n",pred,'Normal')
                 else
                     pred=sprintf("%s\n\nROI Image (Shape Features): %s\n\n\n\n",pred,'Abnormal')
                 end
            end
            app.TESTDATARESULTTextArea.Value=pred;  
        end

        % Button pushed function: ROIABButton
        function roiAb(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTrain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\roiGLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.lazy.IBk');  
            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTrain.csv');
            v3 = String('-T');
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTest.csv');      
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\nPerformace of KNN for Image after binarizing brighter pixels GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutomatedHippoSeg2classGLCMTrain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of KNN for Image after binarizing brighter pixels GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = utestset.numInstances;

            classes = zeros(numInst,1);
            pred='';
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (GLCM..Features): %s\n\n\n\n",pred,'Normal');
                 else
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (GLCM..Features):%s\n\n\n",pred,'Abnormal');
                 end
            end

            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTrain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);            
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\roiShapeusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);
            

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTrain.csv');
            v3 = String('-T');     
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTest.csv');       
            options = cat(1,v1,v2,v3,v4);

            classifier = javaObject('weka.classifiers.lazy.IBk');

            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\n\nPerformace of KNN for Image after binarizing brighter pixels Shape Features FOR TEST DATASET %s\n\n",result,temp);  

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\AutoSegBinShapeTrain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of KNN for Image after binarizing brighter pixels Shape Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);

            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result;  
            
            numInst = utestset.numInstances;
            
            classes = zeros(numInst,1);
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (Shape Features): %s\n\n\n\n",pred,'Normal');
                 else
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (Shape Features): %s\n\n\n\n",pred,'Abnormal');
                 end
            end
            app.TESTDATARESULTTextArea.Value=pred;  
        end

        % Button pushed function: SKULLCLASSIFYRFButton
        function rfClassify(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtrain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.trees.RandomForest');  
            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtrain.csv');
            v3 = String('-T');
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtest.csv');      
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\nPerformace of Random Forest for Skull Stripped Image GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtrain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of Random Forest for Skull Stripped Image GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = testset.numInstances;

            classes = zeros(numInst,1);
            pred=''
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(testset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nSkull Stripped Image (GLCM..Features): %s\n\n\n\n",pred,'Normal')
                 else
                     pred=sprintf("%s\n\nSkull Stripped Image (GLCM..Features):%s\n\n\n",pred,'Abnormal')
                 end
            end

            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetrain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapeusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);
            

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetrain.csv');
            v3 = String('-T');     
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetest.csv');       
            options = cat(1,v1,v2,v3,v4);

            classifier = javaObject('weka.classifiers.trees.RandomForest');

            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\n\nPerformace of Random Forest for Skull Stripped Image Shape Features FOR TEST DATASET %s\n\n",result,temp);  

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetrain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of Random Forest for Skull Stripped Image Shape Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);

            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result;  
            
            numInst = testset.numInstances;
            
            classes = zeros(numInst,1);
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(testset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nSkull Stripped Image (Shape Features): %s\n\n\n\n",pred,'Normal')
                 else
                     pred=sprintf("%s\n\nSkull Stripped Image (Shape Features): %s\n\n\n\n",pred,'Abnormal')
                 end
            end
            app.TESTDATARESULTTextArea.Value=pred;  
        end

        % Button pushed function: SKULLCLASSIFYABButton
        function abClassify(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtrain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.meta.AdaBoostM1');  
            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtrain.csv');
            v3 = String('-T');
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtest.csv');      
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\nPerformace of AdaBoost for Skull Stripped Image GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\skullGLCMtrain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of AdaBoost for Skull Stripped Image GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = testset.numInstances;

            classes = zeros(numInst,1);
            pred=''
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(testset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nSkull Stripped Image (GLCM..Features): %s\n\n\n\n",pred,'Normal')
                 else
                     pred=sprintf("%s\n\nSkull Stripped Image (GLCM..Features):%s\n\n\n",pred,'Abnormal')
                 end
            end

            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetrain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapeusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);
            

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetrain.csv');
            v3 = String('-T');     
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetest.csv');       
            options = cat(1,v1,v2,v3,v4);

            classifier = javaObject('weka.classifiers.meta.AdaBoostM1');

            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\n\nPerformace of AdaBoost for Skull Stripped Image Shape Features FOR TEST DATASET %s\n\n",result,temp);  

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullShapetrain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of AdaBoost for Skull Stripped Image Shape Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);

            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result;  
            
            numInst = testset.numInstances;
            
            classes = zeros(numInst,1);
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(testset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nSkull Stripped Image (Shape Features): %s\n\n\n\n",pred,'Normal')
                 else
                     pred=sprintf("%s\n\nSkull Stripped Image (Shape Features): %s\n\n\n\n",pred,'Abnormal')
                 end
            end
            app.TESTDATARESULTTextArea.Value=pred;  
        end

        % Button pushed function: HIPPOCLASSIFYKNNButton
        function hippoKNN(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\HippoMain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ClusterManualGLCMTest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\hippoManuGLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.lazy.IBk');  
            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\HippoMain.csv');
            v3 = String('-T');
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ClusterManualGLCMTest.csv');      
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\nPerformace of KNN for Clustered Hippocampus Image GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\HippoMain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of KNN for Clustered Hippocampus Image GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = utestset.numInstances;

            classes = zeros(numInst,1);
            pred='';
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nClustered Hippocampus Image (GLCM..Features): %s\n\n\n\n",pred,'Normal');
                 else
                     pred=sprintf("%s\n\nClustered Hippocampus Image (GLCM..Features):%s\n\n\n",pred,'Abnormal');
                 end
            end

            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result;  
            
            
            app.TESTDATARESULTTextArea.Value=pred;  
        end

        % Button pushed function: HIPPOCLASSIFYDTButton
        function hippoDtClassify(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\HippoMain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ClusterManualGLCMTest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\hippoManuGLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.trees.J48');  
            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\HippoMain.csv');
            v3 = String('-T');
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ClusterManualGLCMTest.csv');      
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\nPerformace of Decision Tree for Clustered Hippocampus Image GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\HippoMain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of Decision Tree for Clustered Hippocampus Image GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = utestset.numInstances;

            classes = zeros(numInst,1);
            pred=''
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nClustered Hippocampus Image (GLCM..Features): %s\n\n\n\n",pred,'Normal')
                 else
                     pred=sprintf("%s\n\nClustered Hippocampus Image (GLCM..Features):%s\n\n\n",pred,'Abnormal')
                 end
            end

            
            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result;  
            
            
            app.TESTDATARESULTTextArea.Value=pred;  

        end

        % Button pushed function: HIPPOCLASSIFYRFButton
        function hippoRfClassify(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\HippoMain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ClusterManualGLCMTest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\hippoManuGLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.trees.RandomForest');  
            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\HippoMain.csv');
            v3 = String('-T');
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ClusterManualGLCMTest.csv');      
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\nPerformace of Random Forest for Clustered Hippocampus Image GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\HippoMain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of Random Forest for Clustered Hippocampus Image GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = testset.numInstances;

            classes = zeros(numInst,1);
            pred=''
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(testset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nClustered Hippocampus Image (GLCM..Features): %s\n\n\n\n",pred,'Normal')
                 else
                     pred=sprintf("%s\n\nClustered Hippocampus Image (GLCM..Features):%s\n\n\n",pred,'Abnormal')
                 end
            end

     
            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result;  
            
           
            app.TESTDATARESULTTextArea.Value=pred; 
        end

        % Button pushed function: HIPPOCLASSIFYABButton
        function hippoAbClassify(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\HippoMain.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ClusterManualGLCMTest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\hippoManuGLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.meta.AdaBoostM1');  
            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\HippoMain.csv');
            v3 = String('-T');
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ClusterManualGLCMTest.csv');      
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\nPerformace of AdaBoost for Clustered Hippocampus Image GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\HippoMain.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of AdaBoost for Clustered Hippocampus Image GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = testset.numInstances;

            classes = zeros(numInst,1);
            pred=''
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(testset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nClustered Hippocampus Image (GLCM..Features): %s\n\n\n\n",pred,'Normal')
                 else
                     pred=sprintf("%s\n\nClustered Hippocampus Image (GLCM..Features):%s\n\n\n",pred,'Abnormal')
                 end
            end

           
            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result;  
            
            
            app.TESTDATARESULTTextArea.Value=pred;  
        end

        % Button pushed function: CENTROIDKNNButton
        function connKNN(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_GLCM.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_GLCM.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ConnectedGLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.lazy.IBk');  
            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_GLCM.csv');
            v3 = String('-T');
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_GLCM.csv');      
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\nPerformace of KNN for Image after binarizing brighter pixels GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_GLCM.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of KNN for Image after binarizing brighter pixels GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = utestset.numInstances;

            classes = zeros(numInst,1);
            pred='';
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (GLCM..Features): %s\n\n\n\n",pred,'Normal');
                 else
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (GLCM..Features):%s\n\n\n",pred,'Abnormal');
                 end
            end

            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_Shape.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_Shape.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);            
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ConnectedShapeusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);
            

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_Shape.csv');
            v3 = String('-T');     
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_Shape.csv');       
            options = cat(1,v1,v2,v3,v4);

            classifier = javaObject('weka.classifiers.lazy.IBk');

            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\n\nPerformace of KNN for Image after binarizing brighter pixels Shape Features FOR TEST DATASET %s\n\n",result,temp);  

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_Shape.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of KNN for Image after binarizing brighter pixels Shape Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);

            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result;  
            
            numInst = utestset.numInstances;
            
            classes = zeros(numInst,1);
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (Shape Features): %s\n\n\n\n",pred,'Normal');
                 else
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (Shape Features): %s\n\n\n\n",pred,'Abnormal');
                 end
            end
            app.TESTDATARESULTTextArea.Value=pred;  
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_GLCM.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_GLCM.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ConnectedGLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.lazy.IBk');  
            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_GLCM.csv');
            v3 = String('-T');
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_GLCM.csv');      
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\nPerformace of KNN for Image after binarizing brighter pixels GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_GLCM.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of KNN for Image after binarizing brighter pixels GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = utestset.numInstances;

            classes = zeros(numInst,1);
            pred='';
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (GLCM..Features): %s\n\n\n\n",pred,'Normal');
                 else
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (GLCM..Features):%s\n\n\n",pred,'Abnormal');
                 end
            end

            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_Shape.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_Shape.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);            
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ConnectedShapeusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);
            

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_Shape.csv');
            v3 = String('-T');     
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_Shape.csv');       
            options = cat(1,v1,v2,v3,v4);

            classifier = javaObject('weka.classifiers.lazy.IBk');

            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\n\nPerformace of KNN for Image after binarizing brighter pixels Shape Features FOR TEST DATASET %s\n\n",result,temp);  

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_Shape.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of KNN for Image after binarizing brighter pixels Shape Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);

            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result;  
            
            numInst = utestset.numInstances;
            
            classes = zeros(numInst,1);
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (Shape Features): %s\n\n\n\n",pred,'Normal');
                 else
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (Shape Features): %s\n\n\n\n",pred,'Abnormal');
                 end
            end
            app.TESTDATARESULTTextArea.Value=pred;  
        end

        % Button pushed function: CENTROIDDTButton
        function connDT(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_GLCM.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_GLCM.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ConnectedGLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.trees.J48');  
            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_GLCM.csv');
            v3 = String('-T');
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_GLCM.csv');      
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\nPerformace of Decision Tree for Image after binarizing brighter pixels GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_GLCM.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of Decision Tree for Image after binarizing brighter pixels GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = utestset.numInstances;

            classes = zeros(numInst,1);
            pred=''
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (GLCM..Features): %s\n\n\n\n",pred,'Normal')
                 else
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (GLCM..Features):%s\n\n\n",pred,'Abnormal')
                 end
            end

            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_Shape.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_Shape.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ConnectedShapeusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);
            

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_Shape.csv');
            v3 = String('-T');     
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_Shape.csv');       
            options = cat(1,v1,v2,v3,v4);

            classifier = javaObject('weka.classifiers.trees.J48');

            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\n\nPerformace of Decision Tree for Image after binarizing brighter pixels Shape Features FOR TEST DATASET %s\n\n",result,temp);  

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_Shape.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of Decision Tree for Image after binarizing brighter pixels Shape Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);

            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result;  
            
            numInst = utestset.numInstances;
            
            classes = zeros(numInst,1);
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (Shape Features): %s\n\n\n\n",pred,'Normal')
                 else
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (Shape Features): %s\n\n\n\n",pred,'Abnormal')
                 end
            end
            app.TESTDATARESULTTextArea.Value=pred;  
        end

        % Button pushed function: CENTROIDRFButton
        function connRF(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_GLCM.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_GLCM.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ConnectedGLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.trees.RandomForest');  
            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_GLCM.csv');
            v3 = String('-T');
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_GLCM.csv');      
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\nPerformace of Random Forest for Image after binarizing brighter pixels GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_GLCM.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of Random Forest for Image after binarizing brighter pixels GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = testset.numInstances;

            classes = zeros(numInst,1);
            pred=''
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(testset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (GLCM..Features): %s\n\n\n\n",pred,'Normal')
                 else
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (GLCM..Features):%s\n\n\n",pred,'Abnormal')
                 end
            end

            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_Shape.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_Shape.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ConnectedShapeusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);
            

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_Shape.csv');
            v3 = String('-T');     
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_Shape.csv');       
            options = cat(1,v1,v2,v3,v4);

            classifier = javaObject('weka.classifiers.trees.RandomForest');

            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\n\nPerformace of Random Forest for Image after binarizing brighter pixels Shape Features FOR TEST DATASET %s\n\n",result,temp);  

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_Shape.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of Random Forest for Image after binarizing brighter pixels Shape Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);

            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result;  
            
            numInst = testset.numInstances;
            
            classes = zeros(numInst,1);
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(testset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (Shape Features): %s\n\n\n\n",pred,'Normal')
                 else
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (Shape Features): %s\n\n\n\n",pred,'Abnormal')
                 end
            end
            app.TESTDATARESULTTextArea.Value=pred;  
        end

        % Button pushed function: CENTROIDABButton
        function connAB(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_GLCM.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_GLCM.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ConnectedGLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.meta.AdaBoostM1');  
            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_GLCM.csv');
            v3 = String('-T');
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_GLCM.csv');      
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\nPerformace of AdaBoost for Image after binarizing brighter pixels GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_GLCM.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of AdaBoost for Image after binarizing brighter pixels GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = testset.numInstances;

            classes = zeros(numInst,1);
            pred=''
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(testset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (GLCM..Features): %s\n\n\n\n",pred,'Normal')
                 else
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (GLCM..Features):%s\n\n\n",pred,'Abnormal')
                 end
            end

            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_Shape.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_Shape.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ConnectedShapeusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);
            

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_Shape.csv');
            v3 = String('-T');     
            v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Test_CC_Shape.csv');       
            options = cat(1,v1,v2,v3,v4);

            classifier = javaObject('weka.classifiers.meta.AdaBoostM1');

            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\n\nPerformace of AdaBoost for Image after binarizing brighter pixels Shape Features FOR TEST DATASET %s\n\n",result,temp);  

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\Train_CC_Shape.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of AdaBoost for Image after binarizing brighter pixels Shape Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);

            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result;  
            
            numInst = testset.numInstances;
            
            classes = zeros(numInst,1);
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(testset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (Shape Features): %s\n\n\n\n",pred,'Normal')
                 else
                     pred=sprintf("%s\n\nImage after binarizing brighter pixels (Shape Features): %s\n\n\n\n",pred,'Abnormal')
                 end
            end
            app.TESTDATARESULTTextArea.Value=pred;  
        end

        % Callback function
        function findHippo(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\clusterClassification.csv'));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\skullGLCMtest.csv'));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\ClusterClassificationTest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.lazy.IBk');  
%             v1 = String('-t');
%             v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\clusterClassification.csv');
%             v3 = String('-T');
%             v4 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\skullGLCMtest.csv');      
%             options = cat(1,v1,v2,v3,v4);
% 
%             classifier.buildClassifier(dataset);
%             temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
%             result=sprintf("\nPerformace of KNN for Skull Stripped Image GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            v1 = String('-t');
            v2 = String('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\Review3\ExtractedData\clusterClassification.csv');
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);

            classifier.buildClassifier(dataset);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("\n\n\nPerformace of Clustered Images FOR 3-FOLD CROSS VALIDATION %s\n\n",temp);
            
            app.PERFORMANCEEVALUATIONREPORTTextArea.Value=result; 
            numInst = utestset.numInstances;

            classes = zeros(numInst,1);
            pred=''
            for i=1:numInst
                 classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                 if(classes(i)==0)
                     pred=sprintf("%s\n\nImage classified as : %s\n\n\n\n",pred,'Hippo')
                     if i==1
                         imshow(app.m1,'parent',app.clusterImg_2 );
                     elseif i==2
                         imshow(app.m2,'parent',app.clusterImg_2 );
                     elseif i==3
                         imshow(app.m3,'parent',app.clusterImg_2 );
                     elseif i==4
                         imshow(app.m4,'parent',app.clusterImg_2 );
                     elseif i==5
                         imshow(app.m5,'parent',app.clusterImg_2 );
                     elseif i==6
                         imshow(app.m6,'parent',app.clusterImg_2 );
                     end
                     
                 else
                     pred=sprintf("%s\n\nSkull Stripped Image (GLCM..Features):%s\n\n\n",pred,'Non Hippo')
                 end
            end

            app.TESTDATARESULTTextArea.Value=pred;  

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.inpImg = uiaxes(app.UIFigure);
            title(app.inpImg, 'INPUT IMAGE')
            xlabel(app.inpImg, 'X')
            ylabel(app.inpImg, 'Y')
            zlabel(app.inpImg, 'Z')
            app.strippedImg = uiaxes(app.UIFigure);
            title(app.strippedImg, 'SKULL STRIPPED IMAGE')
            xlabel(app.strippedImg, 'X')
            ylabel(app.strippedImg, 'Y')
            zlabel(app.strippedImg, 'Z')
            app.hippoImg = uiaxes(app.UIFigure);
            title(app.hippoImg, 'HIPPOCAMPUS REGION')
            xlabel(app.hippoImg, 'X')
            ylabel(app.hippoImg, 'Y')
            zlabel(app.hippoImg, 'Z')
            app.clusterImg = uiaxes(app.UIFigure);
            title(app.clusterImg, 'CLUSTER REGION')
            xlabel(app.clusterImg, 'X')
            ylabel(app.clusterImg, 'Y')
            zlabel(app.clusterImg, 'Z')
            app.clusterImg_2 = uiaxes(app.UIFigure);
            title(app.clusterImg_2, 'CLUSTER IMAGE')
            xlabel(app.clusterImg_2, 'X')
            ylabel(app.clusterImg_2, 'Y')
            zlabel(app.clusterImg_2, 'Z')
            app.clusterImg_3 = uiaxes(app.UIFigure);
            title(app.clusterImg_3, 'CLUSTER WITH MORE BRIGHT PIXELS')
            xlabel(app.clusterImg_3, 'X')
            ylabel(app.clusterImg_3, 'Y')
            zlabel(app.clusterImg_3, 'Z')
            app.roiImgAxes = uiaxes(app.UIFigure);
            title(app.roiImgAxes, 'ROI Region')
            xlabel(app.roiImgAxes, 'X')
            ylabel(app.roiImgAxes, 'Y')
            zlabel(app.roiImgAxes, 'Z')
            app.IMPORTButton = uibutton(app.UIFigure, 'push');
            app.IMPORTButton.ButtonPushedFcn = createCallbackFcn(app, @getImg, true);
            app.IMPORTButton.Text = 'IMPORT';
            app.PROCESSButton = uibutton(app.UIFigure, 'push');
            app.PROCESSButton.ButtonPushedFcn = createCallbackFcn(app, @extractInpImg, true);
            app.PROCESSButton.Text = 'PROCESS';
            app.SKULLSTRIPPINGACCURACYEditFieldLabel = uilabel(app.UIFigure);
            app.SKULLSTRIPPINGACCURACYEditFieldLabel.Text = 'SKULL STRIPPING ACCURACY';
            app.SKULLSTRIPPINGACCURACYEditField = uieditfield(app.UIFigure, 'numeric');
            app.SKULLCLASSIFYKNNButton = uibutton(app.UIFigure, 'push');
            app.SKULLCLASSIFYKNNButton.ButtonPushedFcn = createCallbackFcn(app, @knnClassify, true);
            app.SKULLCLASSIFYKNNButton.Text = 'SKULL-CLASSIFY KNN';
            app.SKULLCLASSIFYDTButton = uibutton(app.UIFigure, 'push');
            app.SKULLCLASSIFYDTButton.ButtonPushedFcn = createCallbackFcn(app, @dtClassify, true);
            app.SKULLCLASSIFYDTButton.Text = 'SKULL-CLASSIFY DT';
            app.SKULLCLASSIFYRFButton = uibutton(app.UIFigure, 'push');
            app.SKULLCLASSIFYRFButton.ButtonPushedFcn = createCallbackFcn(app, @rfClassify, true);
            app.SKULLCLASSIFYRFButton.Text = 'SKULL-CLASSIFY RF';
            app.SKULLCLASSIFYABButton = uibutton(app.UIFigure, 'push');
            app.SKULLCLASSIFYABButton.ButtonPushedFcn = createCallbackFcn(app, @abClassify, true);
            app.SKULLCLASSIFYABButton.Text = 'SKULL-CLASSIFY AB';
            app.PERFORMANCEEVALUATIONREPORTTextArea = uitextarea(app.UIFigure);
            app.PIXELSPERCLUSTERTextArea = uitextarea(app.UIFigure);
            app.PROCESSButton_2 = uibutton(app.UIFigure, 'push');
            app.PROCESSButton_2.ButtonPushedFcn = createCallbackFcn(app, @hipoExtract, true);
            app.PROCESSButton_2.Text = 'PROCESS';
            app.PROCESSButton_3 = uibutton(app.UIFigure, 'push');
            app.PROCESSButton_3.ButtonPushedFcn = createCallbackFcn(app, @clusterProcess, true);
            app.PROCESSButton_3.Text = 'PROCESS';
            app.HIPPOCLASSIFYKNNButton = uibutton(app.UIFigure, 'push');
            app.HIPPOCLASSIFYKNNButton.ButtonPushedFcn = createCallbackFcn(app, @hippoKNN, true);
            app.HIPPOCLASSIFYKNNButton.Text = 'HIPPO-CLASSIFY KNN';
            app.HIPPOCLASSIFYDTButton = uibutton(app.UIFigure, 'push');
            app.HIPPOCLASSIFYDTButton.ButtonPushedFcn = createCallbackFcn(app, @hippoDtClassify, true);
            app.HIPPOCLASSIFYDTButton.Text = 'HIPPO-CLASSIFY DT';
            app.HIPPOCLASSIFYRFButton = uibutton(app.UIFigure, 'push');
            app.HIPPOCLASSIFYRFButton.ButtonPushedFcn = createCallbackFcn(app, @hippoRfClassify, true);
            app.HIPPOCLASSIFYRFButton.Text = 'HIPPO-CLASSIFY RF';
            app.HIPPOCLASSIFYABButton = uibutton(app.UIFigure, 'push');
            app.HIPPOCLASSIFYABButton.ButtonPushedFcn = createCallbackFcn(app, @hippoAbClassify, true);
            app.HIPPOCLASSIFYABButton.Text = 'HIPPO-CLASSIFY AB';
            app.FEATUREEXTRACTIONButton = uibutton(app.UIFigure, 'push');
            app.FEATUREEXTRACTIONButton.ButtonPushedFcn = createCallbackFcn(app, @datasetcreation, true);
            app.FEATUREEXTRACTIONButton.Text = 'FEATURE EXTRACTION';
            app.TESTDATARESULTTextAreaLabel = uilabel(app.UIFigure);
            app.TESTDATARESULTTextAreaLabel.Text = 'TEST DATA RESULT';
            app.TESTDATARESULTTextArea = uitextarea(app.UIFigure);
            app.Cluster1Button = uibutton(app.UIFigure, 'push');
            app.Cluster1Button.ButtonPushedFcn = createCallbackFcn(app, @placeImg1, true);
            app.Cluster1Button.Text = 'Cluster1';
            app.Cluster2Button = uibutton(app.UIFigure, 'push');
            app.Cluster2Button.ButtonPushedFcn = createCallbackFcn(app, @placeImg2, true);
            app.Cluster2Button.Text = 'Cluster 2';
            app.Cluster3Button = uibutton(app.UIFigure, 'push');
            app.Cluster3Button.ButtonPushedFcn = createCallbackFcn(app, @placeImg3, true);
            app.Cluster3Button.Text = 'Cluster 3';
            app.Cluster4Button = uibutton(app.UIFigure, 'push');
            app.Cluster4Button.ButtonPushedFcn = createCallbackFcn(app, @placeImg4, true);
            app.Cluster4Button.Text = 'Cluster 4';
            app.Cluster5Button = uibutton(app.UIFigure, 'push');
            app.Cluster5Button.ButtonPushedFcn = createCallbackFcn(app, @placeImg5, true);
            app.Cluster5Button.Text = 'Cluster 5';
            app.Cluster6Button = uibutton(app.UIFigure, 'push');
            app.Cluster6Button.ButtonPushedFcn = createCallbackFcn(app, @placeImg6, true);
            app.Cluster6Button.Text = 'Cluster 6';
            app.PROCESSButton_5 = uibutton(app.UIFigure, 'push');
            app.PROCESSButton_5.ButtonPushedFcn = createCallbackFcn(app, @connectedComp, true);
            app.PROCESSButton_5.Text = 'PROCESS';
            app.CENTROIDKNNButton = uibutton(app.UIFigure, 'push');
            app.CENTROIDKNNButton.ButtonPushedFcn = createCallbackFcn(app, @connKNN, true);
            app.CENTROIDKNNButton.Text = 'CENTROID KNN';
            app.CENTROIDDTButton = uibutton(app.UIFigure, 'push');
            app.CENTROIDDTButton.ButtonPushedFcn = createCallbackFcn(app, @connDT, true);
            app.CENTROIDDTButton.Text = 'CENTROID DT';
            app.CENTROIDRFButton = uibutton(app.UIFigure, 'push');
            app.CENTROIDRFButton.ButtonPushedFcn = createCallbackFcn(app, @connRF, true);
            app.CENTROIDRFButton.Text = 'CENTROID RF';
            app.CENTROIDABButton = uibutton(app.UIFigure, 'push');
            app.CENTROIDABButton.ButtonPushedFcn = createCallbackFcn(app, @connAB, true);
            app.CENTROIDABButton.Text = 'CENTROID AB';
            app.PROCESSButton_6 = uibutton(app.UIFigure, 'push');
            app.PROCESSButton_6.ButtonPushedFcn = createCallbackFcn(app, @roiRegion, true);
            app.PROCESSButton_6.Text = 'PROCESS';
            app.ROIKNNButton = uibutton(app.UIFigure, 'push');
            app.ROIKNNButton.ButtonPushedFcn = createCallbackFcn(app, @roiKNN, true);
            app.ROIKNNButton.Text = 'ROI KNN';
            app.ROIDTButton = uibutton(app.UIFigure, 'push');
            app.ROIDTButton.ButtonPushedFcn = createCallbackFcn(app, @roiDt, true);
            app.ROIDTButton.Text = 'ROI DT';
            app.ROIRFButton = uibutton(app.UIFigure, 'push');
            app.ROIRFButton.ButtonPushedFcn = createCallbackFcn(app, @roiRf, true);
            app.ROIRFButton.Text = 'ROI RF';
            app.ROIABButton = uibutton(app.UIFigure, 'push');
            app.ROIABButton.ButtonPushedFcn = createCallbackFcn(app, @roiAb, true);
            app.ROIABButton.Text = 'ROI AB';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageClassification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end