classdef imgPreprocessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MeanFilterButton           matlab.ui.control.Button
        BilateralButton            matlab.ui.control.Button
        RightArea                  matlab.ui.control.NumericEditField
        RightAreaLabel             matlab.ui.control.Label
        leftArea                   matlab.ui.control.NumericEditField
        LeftAreaLabel              matlab.ui.control.Label
        CalculateButton            matlab.ui.control.Button
        CropRightSideButton        matlab.ui.control.Button
        CropLeftSideButton         matlab.ui.control.Button
        TopHatFilteringButton      matlab.ui.control.Button
        tissuespaceEditField       matlab.ui.control.NumericEditField
        tissuespaceEditFieldLabel  matlab.ui.control.Label
        MedianFilterButton_2       matlab.ui.control.Button
        BrowseButton               matlab.ui.control.Button
        after_5                    matlab.ui.control.UIAxes
        after_4                    matlab.ui.control.UIAxes
        after_3                    matlab.ui.control.UIAxes
        after                      matlab.ui.control.UIAxes
        before                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        img2 % Description
        se % Description
        leftTemp % Description
        rightTemp % Description
        binaryImage % Description
        imageSize % Description
        whole_area % Description
        white_area % Description
        black_area % Description
        patch % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function select(app, event)
            [file,path]= uigetfile('*.*');
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            app.img = imread(fullfile(path,file));
            imshow(app.img,'parent',app.before);
        end

        % Button pushed function: MedianFilterButton_2
        function noiseRemovalMedian(app, event)
            figure(app.UIFigure);            
            app.img2 = medfilt2(app.img,[3,3]);
            app.img2 = imadjust(app.img2);
            imshow(app.img2,'parent',app.after);
        end

        % Button pushed function: TopHatFilteringButton
        function tophat(app, event)
            figure(app.UIFigure);    
            app.se = strel('disk',100);
            app.img2 = imtophat(app.img,app.se);
            app.img2 = imadjust(app.img2);

            imshow(app.img2,'parent',app.after)
        end

        % Button pushed function: CropLeftSideButton
        function leftSection(app, event)
            app.leftTemp= app.img2(70:150,20:60,:);
            imshow(app.leftTemp,'parent',app.after_3)
        end

        % Button pushed function: CropRightSideButton
        function rightSection(app, event)
            app.rightTemp= app.img2(70:150,115:155,:);
            imshow(app.rightTemp,'parent',app.after_4)
        end

        % Button pushed function: CalculateButton
        function findArea(app, event)
            app.binaryImage = app.leftTemp > 40; 
            app.imageSize = size(app.binaryImage);
            app.whole_area = app.imageSize(1)*app.imageSize(2);
            app.black_area = sum(sum(app.binaryImage)); 
            app.white_area = app.whole_area - app.black_area;

            app.leftArea.Value = app.white_area;

            
            app.binaryImage = app.rightTemp > 40; 
            app.imageSize = size(app.binaryImage);
            app.whole_area = app.imageSize(1)*app.imageSize(2);
            app.black_area = sum(sum(app.binaryImage)); 
            app.white_area = app.whole_area - app.black_area;
            
            app.RightArea.Value = app.white_area;
        
            app.tissuespaceEditField.Value = app.leftArea.Value + app.RightArea.Value;

        end

        % Callback function
        function setAreaVal(app, event)
            % value = app.tissuespaceEditField.Value;
            
        end

        % Button pushed function: BilateralButton
        function bilateral(app, event)
            figure(app.UIFigure);   
            app.patch = imcrop(app.img,[60, 35, 20 20]);
            app.img2 = imbilatfilt(app.img,(2*(std2(app.patch)^2)));
            app.img2 = imadjust(app.img2);
            imshow(app.img2,'parent',app.after);            
        end

        % Button pushed function: MeanFilterButton
        function meanFilter(app, event)
            figure(app.UIFigure);            
            app.img2 = imfilter(app.img,fspecial('average',3));
            app.img2 = imadjust(app.img2);
            imshow(app.img2,'parent',app.after);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.before = uiaxes(app.UIFigure);
            title(app.before, 'Input MRI image')
            app.after = uiaxes(app.UIFigure);
            title(app.after, 'Noise Removal')
            app.after_3 = uiaxes(app.UIFigure);
            title(app.after_3, 'Left temporal section')
            app.after_4 = uiaxes(app.UIFigure);
            title(app.after_4, {'Right temporal section'; ''})
            app.after_5 = uiaxes(app.UIFigure);
            title(app.after_5, 'Skull Stripping ')
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @select, true);
            app.BrowseButton.Text = 'Browse ';
            app.MedianFilterButton_2 = uibutton(app.UIFigure, 'push');
            app.MedianFilterButton_2.ButtonPushedFcn = createCallbackFcn(app, @noiseRemovalMedian, true);
            app.MedianFilterButton_2.Text = 'Median Filter';
            app.tissuespaceEditFieldLabel = uilabel(app.UIFigure);
            app.tissuespaceEditFieldLabel.Text = 'tissue space ';
            app.tissuespaceEditField = uieditfield(app.UIFigure, 'numeric');
            app.TopHatFilteringButton = uibutton(app.UIFigure, 'push');
            app.TopHatFilteringButton.ButtonPushedFcn = createCallbackFcn(app, @tophat, true);
            app.TopHatFilteringButton.Text = 'Top Hat Filtering ';
            app.CropLeftSideButton = uibutton(app.UIFigure, 'push');
            app.CropLeftSideButton.ButtonPushedFcn = createCallbackFcn(app, @leftSection, true);
            app.CropLeftSideButton.Text = 'Crop Left Side';
            app.CropRightSideButton = uibutton(app.UIFigure, 'push');
            app.CropRightSideButton.ButtonPushedFcn = createCallbackFcn(app, @rightSection, true);
            app.CropRightSideButton.Text = 'Crop Right Side';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @findArea, true);
            app.CalculateButton.Text = 'Calculate';
            app.LeftAreaLabel = uilabel(app.UIFigure);
            app.LeftAreaLabel.Text = 'Left Area';
            app.leftArea = uieditfield(app.UIFigure, 'numeric');
            app.RightAreaLabel = uilabel(app.UIFigure);
            app.RightAreaLabel.Text = 'Right Area';
            app.RightArea = uieditfield(app.UIFigure, 'numeric');
            app.BilateralButton = uibutton(app.UIFigure, 'push');
            app.BilateralButton.ButtonPushedFcn = createCallbackFcn(app, @bilateral, true);
            app.BilateralButton.Text = 'Bilateral ';
            app.MeanFilterButton = uibutton(app.UIFigure, 'push');
            app.MeanFilterButton.ButtonPushedFcn = createCallbackFcn(app, @meanFilter, true);
            app.MeanFilterButton.Text = 'Mean Filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imgPreprocessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end