classdef multiclass < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        optionsMenu                     matlab.ui.container.Menu
        SkullStrippedMenu               matlab.ui.container.Menu
        CroppedROIMenu                  matlab.ui.container.Menu
        ManualROIMenu                   matlab.ui.container.Menu
        Cluster1Menu                    matlab.ui.container.Menu
        Cluster2Menu                    matlab.ui.container.Menu
        Cluster3Menu                    matlab.ui.container.Menu
        Cluster4Menu                    matlab.ui.container.Menu
        Cluster5Menu                    matlab.ui.container.Menu
        Cluster6Menu                    matlab.ui.container.Menu
        AutomatedROIMenu                matlab.ui.container.Menu
        SegmentedROIMenu                matlab.ui.container.Menu
        ClassifyMenu                    matlab.ui.container.Menu
        KNNMenu                         matlab.ui.container.Menu
        DecisionTreeMenu                matlab.ui.container.Menu
        RandomForestMenu                matlab.ui.container.Menu
        AdaBoosterMenu                  matlab.ui.container.Menu
        ExtractFeaturesButton           matlab.ui.control.Button
        ResultTextArea                  matlab.ui.control.TextArea
        ResultTextAreaLabel             matlab.ui.control.Label
        EvaluationofmodelTextArea       matlab.ui.control.TextArea
        EvaluationofmodelTextAreaLabel  matlab.ui.control.Label
        InputButton                     matlab.ui.control.Button
        imgUsed                         matlab.ui.control.UIAxes
        inpImg                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        group % Description
        LoadedImg
        origImg
        skullImg
        finalSkullImg
        imageAtAxes
        hippocampImg
        ows
        columns
        numberOfColorChannels
        one
        two
        three
        four
        five
        six
        P1
        P2
        P3
        P4
        P5
        P6
        middleRow
        m1
        m2
        m3
        m4
        m5
        m6
        currentmanu
        connImg
        manuClusterImg
        currentImg
        file1
        file2
        finalSegImg
    end
    
    methods (Access = private)
        
        function getShapeData(app)

            excel = actxserver('Excel.Application');  
            excelWorkbook = excel.Workbooks.Add(1);   
            excelSheet = excel.ActiveSheet;           
            
            excelSheet.Range('A1').Value = "Area";
            excelSheet.Range('B1').Value = "Bounding Box";
            excelSheet.Range('C1').Value = "Convex Area";
            excelSheet.Range('D1').Value = "Eccentricity";
            excelSheet.Range('E1').Value = "EquivDiameter";
            excelSheet.Range('F1').Value = "EulerNumber";
            excelSheet.Range('G1').Value = "FilledArea";
            excelSheet.Range('H1').Value = "MajorAxisLength";
            excelSheet.Range('I1').Value = "MinorAxisLength";
            excelSheet.Range('J1').Value = "Orientation";
            excelSheet.Range('K1').Value = "Perimeter";
            excelSheet.Range('L1').Value = "Ground Truth Value";

            start=2;

            cellA=append('A',int2str(start));
            aText=append(num2str(bwarea(app.currentImg)));
            excelSheet.Range(cellA).Value = aText;
        
            t=regionprops(app.currentImg,'BoundingBox');
            cellB=append('B',int2str(start));
            aText=append(num2str(mean([t.BoundingBox])));
            excelSheet.Range(cellB).Value = aText;      
                              
            t=regionprops(app.currentImg,'ConvexArea');
            cellC=append('C',int2str(start));
            aText=append(num2str(mean([t.ConvexArea])));
            excelSheet.Range(cellC).Value = aText;
        
            t=regionprops(app.currentImg,'Eccentricity');
            cellD=append('D',int2str(start));
            aText=append(num2str(mean([t.Eccentricity])));
            excelSheet.Range(cellD).Value = aText;
        
            t=regionprops(app.currentImg,'EquivDiameter');
            cellE=append('E',int2str(start));
            aText=append(num2str(mean([t.EquivDiameter])));
            excelSheet.Range(cellE).Value = aText;
                
            cellF=append('F',int2str(start));
            temp=num2str(bweuler(app.currentImg));
            aText=append(temp);
            excelSheet.Range(cellF).Value = aText;

            t=regionprops(app.currentImg,'FilledArea');
            cellG=append('G',int2str(start));
            aText=append(num2str(mean([t.FilledArea])));
            excelSheet.Range(cellG).Value = aText;        
                
            t=regionprops(app.currentImg,'MajorAxisLength');
            cellH=append('H',int2str(start));
            aText=append(num2str(mean([t.MajorAxisLength])));
            excelSheet.Range(cellH).Value = aText;
        
            t=regionprops(app.currentImg,'MinorAxisLength');
            cellI=append('I',int2str(start));
            aText=append(num2str(mean([t.MinorAxisLength])));
            excelSheet.Range(cellI).Value = aText;
            
            t=regionprops(app.currentImg,'Orientation');
            cellJ=append('J',int2str(start));
            aText=append(num2str(mean([t.Orientation])));
            excelSheet.Range(cellJ).Value = aText;
        
            t=regionprops(app.currentImg,'Perimeter');
            cellK=append('K',int2str(start));
            aText=append(num2str(mean([t.Perimeter])));
            excelSheet.Range(cellK).Value = aText;       
                              
            cellL=append('L',int2str(start));               
            aText=append((app.group));
            excelSheet.Range(cellL).Value = aText;

            excelWorkbook.SaveAs('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\Shapeusertest.xlsx');             
            excelWorkbook.Close();                 
            excel.Quit();                         
            excel.delete();                       

            T= readtable('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\Shapeusertest.xlsx','ReadRowNames',true);
            writetable(T,'C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\Shapeusertest.csv','WriteRowNames',true);


        end
        
        function getGLCMData(app)

            excel = actxserver('Excel.Application');  
            excelWorkbook = excel.Workbooks.Add(1);   
            excelSheet = excel.ActiveSheet;        
     
            excelSheet.Range('A1').Value = "Mean";
            excelSheet.Range('B1').Value = "Standard Deviation";
            excelSheet.Range('C1').Value = "Variance";
            excelSheet.Range('D1').Value = "Entropy";
            excelSheet.Range('E1').Value = "Maximum G Intensity";
            excelSheet.Range('F1').Value = "Minimum G Intensity";
            excelSheet.Range('G1').Value = "Skewness";
            excelSheet.Range('H1').Value = "Kurtosis";
            excelSheet.Range('I1').Value = "Contrast";
            excelSheet.Range('J1').Value = "Correlation";
            excelSheet.Range('K1').Value = "Energy";
            excelSheet.Range('L1').Value = "Homogenity";
            excelSheet.Range('M1').Value = "AutoCorrelation";
            excelSheet.Range('N1').Value = "Cluster Prominence";
            excelSheet.Range('O1').Value = "Cluster Shade";
            excelSheet.Range('P1').Value = "Dissimilarity";
            excelSheet.Range('Q1').Value = "MaximumProbability";
            excelSheet.Range('R1').Value = "Sum of sqaures";
            excelSheet.Range('S1').Value = "Sum average";
            excelSheet.Range('T1').Value = "Sum Variance";
            excelSheet.Range('U1').Value = "Sum entropy ";
            excelSheet.Range('V1').Value = "Difference variance";
            excelSheet.Range('W1').Value = "Difference Entropy";
            excelSheet.Range('X1').Value = "Information measure of correlation1";
            excelSheet.Range('Y1').Value = "Information measure of correlation2";
            excelSheet.Range('Z1').Value = "Inverse Difference";
            excelSheet.Range('AA1').Value = "Inverse Difference Normalized";
            excelSheet.Range('AB1').Value = "Inverse Difference Moment Normalized";
            excelSheet.Range('AC1').Value = "Ground Truth Value";
            
            start=2;

            cellA=append('A',int2str(start));
            lefted = num2str(mean2(app.currentImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellA).Value = aText;
            
            cellB=append('B',int2str(start));
            l1=double(app.currentImg);
            lefted =num2str(std(l1(:))); 
            aText=append(lefted);
            excelSheet.Range(cellB).Value = aText;
                            
            cellC=append('C',int2str(start));
            l1=double(app.currentImg);
            lefted =num2str(var(l1(:)));
            aText=append(lefted);
            excelSheet.Range(cellC).Value = aText;
            
            cellD=append('D',int2str(start));
            lefted = num2str(entropy(app.currentImg));
            aText=append(lefted);
            excelSheet.Range(cellD).Value = aText;           
            
            cellE=append('E',int2str(start));
            lefted = num2str(max(app.currentImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellE).Value = aText;
            
            cellF=append('F',int2str(start));
            lefted = num2str(min(app.currentImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellF).Value = aText;
            
            cellG=append('G',int2str(start));
            lefted = num2str(skewness(app.currentImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellG).Value = aText;
            
            cellH=append('H',int2str(start));
            lefted = num2str(kurtosis(app.currentImg(:)));
            aText=append(lefted);
            excelSheet.Range(cellH).Value = aText;
            
            cellI=append('I',int2str(start));
            lefted =num2str(graycoprops(app.currentImg).Contrast);
            aText=append(lefted);
            excelSheet.Range(cellI).Value = aText;
            
            cellJ=append('J',int2str(start));
            lefted =num2str(graycoprops(app.currentImg).Correlation);
            aText=append(lefted);
            excelSheet.Range(cellJ).Value = aText;            
                        
            cellK=append('K',int2str(start));
            lefted =num2str(graycoprops(app.currentImg).Energy);
            aText=append(lefted);
            excelSheet.Range(cellK).Value = aText;
            
            cellL=append('L',int2str(start));
            lefted =num2str(graycoprops(app.currentImg).Homogeneity);
            aText=append(lefted);
            excelSheet.Range(cellL).Value = aText;
            
            glcm=graycomatrix(app.currentImg);
            stats=GLCM_Features1(glcm,0);
            statsTable=struct2table(stats);
            statsArray=table2array(statsTable);
            display(statsTable.autoc);            
            
            cellM=append('M',int2str(start));
            lefted =num2str(statsTable.autoc);
            aText=append(lefted);
            excelSheet.Range(cellM).Value = aText;
            
            cellN=append('N',int2str(start));
            lefted =num2str(statsTable.cprom);
            aText=append(lefted);
            excelSheet.Range(cellN).Value = aText;
            
            cellO=append('O',int2str(start));
            lefted =num2str(statsTable.cshad);
            aText=append(lefted);
            excelSheet.Range(cellO).Value = aText;
            
            cellP=append('P',int2str(start));
            lefted =num2str(statsTable.dissi);
            aText=append(lefted);
            excelSheet.Range(cellP).Value = aText;
            
            cellQ=append('Q',int2str(start));
            lefted =num2str(statsTable.maxpr);
            aText=append(lefted);
            excelSheet.Range(cellQ).Value = aText;
            
            cellR=append('R',int2str(start));
            lefted =num2str(statsTable.sosvh);
            aText=append(lefted);
            excelSheet.Range(cellR).Value = aText;
            
            cellS=append('S',int2str(start));
            lefted =num2str(statsTable.savgh);
            aText=append(lefted);
            excelSheet.Range(cellS).Value = aText;
            
            cellT=append('T',int2str(start));
            lefted =num2str(statsTable.svarh);
            aText=append(lefted);
            excelSheet.Range(cellT).Value = aText;
            
            cellU=append('U',int2str(start));
            lefted =num2str(statsTable.senth);
            aText=append(lefted);
            excelSheet.Range(cellU).Value = aText;
            
            cellV=append('V',int2str(start));
            lefted =num2str(statsTable.dvarh);
            aText=append(lefted);
            excelSheet.Range(cellV).Value = aText;
            
            cellW=append('W',int2str(start));
            lefted =num2str(statsTable.denth);
            aText=append(lefted);
            excelSheet.Range(cellW).Value = aText;
            
            cellX=append('X',int2str(start));
            lefted =num2str(statsTable.inf1h);
            aText=append(lefted);
            excelSheet.Range(cellX).Value = aText;
            
            cellY=append('Y',int2str(start));
            lefted =num2str(statsTable.inf2h);
            aText=append(lefted);
            excelSheet.Range(cellY).Value = aText;
            
            cellZ=append('Z',int2str(start));
            lefted =num2str(statsTable.homom);
            aText=append(lefted);
            excelSheet.Range(cellZ).Value = aText;
            
            cellAA=append('AA',int2str(start));
            lefted =num2str(statsTable.indnc);
            aText=append(lefted);
            excelSheet.Range(cellAA).Value = aText;
            
            cellAB=append('AB',int2str(start));
            lefted =num2str(statsTable.idmnc);
            aText=append(lefted);
            excelSheet.Range(cellAB).Value = aText;
                        
            cellAC=append('AC',int2str(start));
            aText=append((app.group));
            excelSheet.Range(cellAC).Value = aText; 


            excelWorkbook.SaveAs('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\GLCMusertest.xlsx'); 
            excelWorkbook.Close();               
            excel.Quit();                         
            excel.delete();                      

            T= readtable('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\GLCMusertest.xlsx','ReadRowNames',true);
            writetable(T,'C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\GLCMusertest.csv','WriteRowNames',true);

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputButton
        function importImage(app, event)
            [file,path]= uigetfile('*.*');           
            newstr=extract(path,"Final CN JPEG");
            if newstr=="Final CN JPEG"
                app.group="CN";
            elseif newstr=="Final AD JPEG"
                app.group="AD";
            elseif newstr=="Final MCI JPEG"
                app.group="MCI";
            elseif newstr=="Final EMCI JPEG"
                app.group="EMCI";
            else
                app.group="LMCI";            
            end        
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            app.LoadedImg  = imread(fullfile(path,file));
            app.origImg=app.LoadedImg;
            app.LoadedImg=adapthisteq(app.LoadedImg,'clipLimit',0.02,'Distribution','rayleigh');
            imshow(app.LoadedImg ,'parent',app.inpImg);
        end

        % Menu selected function: SkullStrippedMenu
        function skullStrip(app, event)
            app.skullImg=app.LoadedImg > 55;
            app.skullImg=bwareaopen(app.skullImg,10);
            app.skullImg=imfill(app.skullImg,'holes');
            se=strel('disk',15,0);
            app.skullImg=imerode(app.skullImg,se);
            app.finalSkullImg=app.LoadedImg;
            app.finalSkullImg(~app.skullImg)=0;            
            figure(app.UIFigure);
            app.imageAtAxes='Skull'
            app.currentImg=app.finalSkullImg
            imshow(app.finalSkullImg,'parent',app.imgUsed);             
        end

        % Menu selected function: CroppedROIMenu
        function croppedHippo(app, event)
            [rows, columns, numberOfColorChannels] = size(app.LoadedImg);
            middleRow = round(rows/2);
            middleColumn = round(columns/2);
            app.hippocampImg=app.LoadedImg;
            [rows, cols, chans, ~] = size(app.hippocampImg);
            for r = 1:rows
                for s=1:cols
                    if(r<middleRow | r>middleRow+70 | s<70 | s>170)
                        app.hippocampImg(r,s)=0;
                    else
                        app.hippocampImg(r,s)=app.hippocampImg(r,s);
                    end
                end
            end
            app.imageAtAxes='CroppedHippo'
            app.currentImg=app.hippocampImg
            figure(app.UIFigure);
            imshow(app.hippocampImg,'parent',app.imgUsed);  
        end

        % Menu selected function: ManualROIMenu
        function manualClusterHippo(app, event)
            [pixel,centroid]=imsegkmeans(app.LoadedImg,6);
            app.manuClusterImg= labeloverlay(app.LoadedImg,pixel);
            app.P1="";
            app.P2="";
            app.P3="";
            app.P4="";
            app.P5="";
            app.P6="";
            app.one=0;
            app.two=0;
            app.three=0;
            app.four=0;
            app.five=0;
            app.six=0;

            app.m1=app.LoadedImg;
            app.m2=app.LoadedImg;
            app.m3=app.LoadedImg;
            app.m4=app.LoadedImg;
            app.m5=app.LoadedImg;
            app.m6=app.LoadedImg;

            [rows, cols, chans, ~] = size(app.manuClusterImg);

             for r = 1:rows
                for s=1:cols
                    if(pixel(r,s)==1)
                        app.m1(r,s)=app.LoadedImg(r,s);
                        app.m2(r,s)=0;
                        app.m3(r,s)=0;
                        app.m4(r,s)=0;
                        app.m5(r,s)=0;
                        app.m6(r,s)=0;
                        if(app.P1=="")
                            app.P1 = sprintf(app.manuClusterImg(r,s,1)+" "+app.manuClusterImg(r,s,2)+" "+app.manuClusterImg(r,s,3));
                        end
                        app.one=app.one+1;
                    end
                    if(pixel(r,s)==2)
                        app.m1(r,s)=0;
                        app.m2(r,s)=app.LoadedImg(r,s);
                        app.m3(r,s)=0;
                        app.m4(r,s)=0;
                        app.m5(r,s)=0;
                        app.m6(r,s)=0;
                        if(app.P2=="")
                            app.P2 = sprintf(app.manuClusterImg(r,s,1)+" "+app.manuClusterImg(r,s,2)+" "+app.manuClusterImg(r,s,3));%impixel(app.manuClusterImg,r,s);
                        end
                        app.two=app.two+1;
                    end
                    if(pixel(r,s)==3)
                        app.m1(r,s)=0;
                        app.m2(r,s)=0;
                        app.m3(r,s)=app.LoadedImg(r,s);
                        app.m4(r,s)=0;
                        app.m5(r,s)=0;
                        app.m6(r,s)=0;

                        if(app.P3=="")   
                            app.P3 = sprintf(app.manuClusterImg(r,s,1)+" "+app.manuClusterImg(r,s,2)+" "+app.manuClusterImg(r,s,3));
                        end
                        app.three=app.three+1;
                    end
                    if(pixel(r,s)==4)
                        app.m1(r,s)=0;
                        app.m2(r,s)=0;
                        app.m3(r,s)=0;
                        app.m4(r,s)=app.LoadedImg(r,s);
                        app.m5(r,s)=0;
                        app.m6(r,s)=0;

                        if(app.P4=="")
                            app.P4 = sprintf(app.manuClusterImg(r,s,1)+" "+app.manuClusterImg(r,s,2)+" "+app.manuClusterImg(r,s,3));
                        end
                        app.four=app.four+1;
                    end
                    if(pixel(r,s)==5)
                        app.m1(r,s)=0;
                        app.m2(r,s)=0;
                        app.m3(r,s)=0;
                        app.m4(r,s)=0;
                        app.m5(r,s)=app.LoadedImg(r,s);
                        app.m6(r,s)=0;

                        if(app.P5=="")
                            app.P5 = sprintf(app.manuClusterImg(r,s,1)+" "+app.manuClusterImg(r,s,2)+" "+app.manuClusterImg(r,s,3));
                        end
                        app.five=app.five+1;
                    end
                    if(pixel(r,s)==6)
                        app.m1(r,s)=0;
                        app.m2(r,s)=0;
                        app.m3(r,s)=0;
                        app.m4(r,s)=0;
                        app.m5(r,s)=0;
                        app.m6(r,s)=app.LoadedImg(r,s);

                        if(app.P6=="")
                            app.P6 = sprintf(app.manuClusterImg(r,s,1)+" "+app.manuClusterImg(r,s,2)+" "+app.manuClusterImg(r,s,3));
                        end
                        app.six=app.six+1;
                    end
                end
             end
             app.imageAtAxes='ManuHippo'
             app.currentImg=app.manuClusterImg
             figure(app.UIFigure);    
             imshow(app.manuClusterImg,'parent',app.imgUsed);  
        end

        % Menu selected function: Cluster1Menu
        function setC1(app, event)
%             figure(app.UIFigure);   
%             imshow(app.m1,'parent',app.imgUsed);

            app.imageAtAxes='ManuHippo'
            app.currentImg=app.m1;

            app.currentmanu=app.m1;

            figure(app.UIFigure);    
            imshow(app.m1,'parent',app.imgUsed);  
        end

        % Menu selected function: Cluster2Menu
        function setC2(app, event)
%             imshow(app.m2,'parent',app.imgUsed);
%             app.imageAtAxes='ManuHippo'

            app.currentImg=app.m2;
            app.currentmanu=app.m2;
            figure(app.UIFigure);    
            imshow(app.m2,'parent',app.imgUsed);  
        end

        % Menu selected function: Cluster3Menu
        function setC3(app, event)
%             figure(app.UIFigure);   
%             imshow(app.m3,'parent',app.imgUsed);
            app.imageAtAxes='ManuHippo'
            app.currentImg=app.m3;
            app.currentmanu=app.m3;
            figure(app.UIFigure);    
            imshow(app.m3,'parent',app.imgUsed);  
        end

        % Menu selected function: Cluster4Menu
        function setC4(app, event)
%             figure(app.UIFigure);   
%             imshow(app.m4,'parent',app.imgUsed);
            app.imageAtAxes='ManuHippo'
            app.currentImg=app.m4;
            app.currentmanu=app.m4;
            figure(app.UIFigure);    
            imshow(app.m4,'parent',app.imgUsed);  
        end

        % Menu selected function: Cluster5Menu
        function setC5(app, event)
%             figure(app.UIFigure);   
%             imshow(app.m5,'parent',app.imgUsed);
            app.imageAtAxes='ManuHippo'
            app.currentImg=app.m5;
            app.currentmanu=app.m5;
            figure(app.UIFigure);    
            imshow(app.m5,'parent',app.imgUsed);  
        end

        % Menu selected function: Cluster6Menu
        function setC6(app, event)
%             figure(app.UIFigure);   
%             imshow(app.m6,'parent',app.imgUsed);
            app.imageAtAxes='ManuHippo'
            app.currentImg=app.m6;
            app.currentmanu=app.m6;
            figure(app.UIFigure);    
            imshow(app.m6,'parent',app.imgUsed);  
        end

        % Menu selected function: AutomatedROIMenu
        function autoROI(app, event)
            h=[0 1 1;
            1 1 1;
            1 1 0]/7;
            im_mean=imfilter(app.LoadedImg,h);
            %im_mean = imlocalbrighten(im_mean);
            figure 
            hold on
            se = strel('disk',3);
            im_top=im_mean-imtophat(im_mean,se);
            im_adjust=imadjust(im_top);

            [L,cen] = imsegkmeans(im_adjust,3);
            B = labeloverlay(im_adjust,L);
            T=(max(cen));
            BW = imbinarize(im_adjust,0.8);
            %display(im_k)

            % threshold 
            t = T;
            % find values below
            ind_below = (im_adjust < t);
            % find values above
            ind_above = (im_adjust >= t);
            % set values below to black
            im_adjust(ind_below) = 0;
            % set values above to white
            im_adjust(ind_above) = 255;

            %disp(im_adjust)

            cc4 = bwconncomp(im_adjust,4);

            L4 = labelmatrix(cc4);
            RGB_label = label2rgb(L4,@copper,"c","shuffle");
            app.connImg=app.LoadedImg;
            app.connImg(~BW)=0;  

            app.imageAtAxes='Auto'
            app.currentImg=app.connImg;
            imshow(app.connImg,'parent',app.imgUsed);
        end

        % Menu selected function: SegmentedROIMenu
        function segmentROI(app, event)
 
            h=[0 1 1;
            1 1 1;
            1 1 0]/7;
            im_mean=imfilter(app.LoadedImg,h);
            figure 
            hold on
            se = strel('disk',3);
            im_top=im_mean-imtophat(im_mean,se);
            im_adjust=imadjust(im_top);
            [L,cen] = imsegkmeans(im_adjust,3);
            B = labeloverlay(im_adjust,L);
            T=(max(cen));
            BW = imbinarize(im_adjust,0.8);
            t = T;
            ind_below = (im_adjust < t);
            ind_above = (im_adjust >= t);
            im_adjust(ind_below) = 0;
            im_adjust(ind_above) = 255;
            cc4 = bwconncomp(im_adjust,4);
            L4 = labelmatrix(cc4);
            RGB_label = label2rgb(L4,@copper,"c","shuffle");
            app.connImg=app.LoadedImg;
            app.connImg(~BW)=0;  
            
            binaryImg1=app.LoadedImg
            binaryImg2=app.LoadedImg
            
            
            bw = bwareafilt(BW, 1);
            bloc = bwboundaries(bw,'noholes');
            roipos = fliplr(bloc{1});
            imshow(app.LoadedImg);
            hfh = drawfreehand('Position', roipos);
            binaryImg1=createMask(hfh)
            
            bw = bwareafilt(BW, 2);
            bloc = bwboundaries(bw,'noholes');
            roipos = fliplr(bloc{1});
            imshow(app.LoadedImg);
            hfh = drawfreehand('Position', roipos);
            binaryImg2=createMask(hfh)
            
            finalMask=imfuse(binaryImg1,binaryImg2)
            finalMask=rgb2gray(finalMask)
            imshow(finalMask)
            app.finalSegImg=app.LoadedImg
            app.finalSegImg(~finalMask)=0;   
            imshow(app.finalSegImg)

            app.imageAtAxes='Seg'
            app.currentImg=app.finalSegImg;
            imshow(app.finalSegImg,'parent',app.imgUsed);   
        end

        % Button pushed function: ExtractFeaturesButton
        function classify(app, event)
            if app.imageAtAxes=="ManuHippo"
                app.getGLCMData();            
            else
                app.getShapeData();
                app.getGLCMData();
            end


            if app.imageAtAxes=="ManuHippo"
                app.file1="ClusterManualGLCMString";
            elseif app.imageAtAxes=="Skull"
                app.file1='SkullGLCMString'
                app.file2='SkullShapeString'
            elseif app.imageAtAxes=="CroppedHippo"
                app.file1='CroppingHippoGLCMString'
                app.file2='CroppingHippoShapeString'
            elseif app.imageAtAxes=="Seg"
                app.file1="AutomatedHippoSeg5classGLCM"
                app.file2="AutoSeg5ClassesShape"
            else
                app.file1='ClusterAutomatedGLCMString'
                app.file2='ClusterAutomatedShapeString'
            end
            
        end

        % Menu selected function: KNNMenu
        function KNNClassify(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
            import java.lang.Exception

             
            train=append('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\',app.file1,'Train.csv');
            test=append('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\',app.file1,'Test.csv');
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File', train));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File',test));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\GLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.lazy.IBk');  
%             v1 = String('-t');
%             v2 = String(train);
%             v3 = String('-T');
%             v4 = String(test);      
%             options = cat(1,v1,v2,v3,v4);
% 
%             classifier.buildClassifier(dataset);
%             temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
%             result=sprintf("\nPerformace of KNN on GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            result=""
            v1 = String('-t');
            v2 = String(train);
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of KNN on GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = utestset.numInstances;

            classes = zeros(numInst,1);
            pred='';
            for i=1:numInst
                classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                if(strcmp(classes(i),"CN"))
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'CN');
                elseif(strcmp(classes(i),"EMCI"))
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'EMCI');
                elseif(strcmp(classes(i),"MCI"))
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'MCI');
                elseif(strcmp(classes(i),"LMCI"))
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'LMCI');
                else
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'AD');
                end
            end

            if app.imageAtAxes~="ManuHippo"
              
                train=append('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\',app.file2,'Train.csv');
                test=append('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\',app.file2,'Test.csv');
            
    
                conv.setFile(javaObject('java.io.File',train));
                dataset=conv.getDataSet;
                dataset.toString;
                dataset.setClassIndex(dataset.numAttributes() - 1);
                conv.setFile(javaObject('java.io.File',test));
                testset=conv.getDataSet;
                testset.toString;
                testset.setClassIndex(testset.numAttributes() - 1);
                conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\Shapeusertest.csv'));
                utestset=conv.getDataSet;
                utestset.toString;
                utestset.setClassIndex(utestset.numAttributes() - 1);
                
    
%                 v1 = String('-t');
%                 v2 = String(train);
%                 v3 = String('-T');     
%                 v4 = String(test);       
%                 options = cat(1,v1,v2,v3,v4);
%     
                classifier = javaObject('weka.classifiers.lazy.IBk');
    
%                 temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
%                 result=sprintf("%s\n\n\n\nPerformace of KNN on Shape Features FOR TEST DATASET %s\n\n",result,temp);  
    
                result=""
                v1 = String('-t');
                v2 = String(train);
                v3 = String('-x');     
                v4 = String('3');       
                options = cat(1,v1,v2,v3,v4);
                
                temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
                result=sprintf("%s\n\n\nPerformace of KNN on Shape Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);

                numInst = utestset.numInstances;
            
                classes = zeros(numInst,1);
                for i=1:numInst
                     classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                     if(strcmp(classes(i),"CN"))
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'CN');
                    elseif(strcmp(classes(i),"EMCI"))
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'EMCI');
                    elseif(strcmp(classes(i),"MCI"))
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'MCI');
                    elseif(strcmp(classes(i),"LMCI"))
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'LMCI');
                    else
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'AD');
                    end
                end
            end
            app.EvaluationofmodelTextArea.Value=result;
            
            
            app.ResultTextArea.Value=pred; 
        end

        % Menu selected function: DecisionTreeMenu
        function dfClassify(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System

            train=append('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\',app.file1,'Train.csv');
            test=append('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\',app.file1,'Test.csv');
        
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File',train));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File',test));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\GLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.trees.J48');  
%             v1 = String('-t');
%             v2 = String(train);
%             v3 = String('-T');
%             v4 = String(test);      
%             options = cat(1,v1,v2,v3,v4);
% 
%             classifier.buildClassifier(dataset);
%             temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
%             result=sprintf("\nPerformace of Decision Tree on GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            result=""
            v1 = String('-t');
            v2 = String(train);
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of Decision Tree on GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = utestset.numInstances;

            classes = zeros(numInst,1);
            pred=''
            for i=1:numInst
                classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                if(strcmp(classes(i),"CN"))
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'CN');
                elseif(strcmp(classes(i),"EMCI"))
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'EMCI');
                elseif(strcmp(classes(i),"MCI"))
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'MCI');
                elseif(strcmp(classes(i),"LMCI"))
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'LMCI');
                else
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'AD');
                end
            end


            if app.imageAtAxes~="ManuHippo"

                train=append('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\',app.file2,'Train.csv');
                test=append('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\',app.file2,'Test.csv');


                conv.setFile(javaObject(train));
                dataset=conv.getDataSet;
                dataset.toString;
                dataset.setClassIndex(dataset.numAttributes() - 1);
                conv.setFile(javaObject('java.io.File',test));
                testset=conv.getDataSet;
                testset.toString;
                testset.setClassIndex(testset.numAttributes() - 1);
                conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\Shapeusertest.csv'));
                utestset=conv.getDataSet;
                utestset.toString;
                utestset.setClassIndex(utestset.numAttributes() - 1);
                
    
%                 v1 = String('-t');
%                 v2 = String(train);
%                 v3 = String('-T');     
%                 v4 = String(test);       
%                 options = cat(1,v1,v2,v3,v4);
    
                classifier = javaObject('weka.classifiers.trees.J48');
    
%                 temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
%                 result=sprintf("%s\n\n\n\nPerformace of Decision Tree in Shape Features FOR TEST DATASET %s\n\n",result,temp);  
    
                result=""
                v1 = String('-t');
                v2 = String(train);
                v3 = String('-x');     
                v4 = String('3');       
                options = cat(1,v1,v2,v3,v4);
                
                temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
                result=sprintf("%s\n\n\nPerformace of Decision Tree on Shape Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);


                numInst = utestset.numInstances;

                classes = zeros(numInst,1);
                for i=1:numInst
                    classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                    if(strcmp(classes(i),"CN"))
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'CN');
                    elseif(strcmp(classes(i),"EMCI"))
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'EMCI');
                    elseif(strcmp(classes(i),"MCI"))
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'MCI');
                    elseif(strcmp(classes(i),"LMCI"))
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'LMCI');
                    else
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'AD');
                    end
                end      
            end
            app.EvaluationofmodelTextArea.Value=result;  
            app.ResultTextArea.Value=pred;  
        end

        % Menu selected function: RandomForestMenu
        function randomForestClass(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System
        
            train=append('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\',app.file1,'Train.csv');
            test=append('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\',app.file1,'Test.csv');
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File',train));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File',test));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\GLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.trees.RandomForest');  
%             v1 = String('-t');
%             v2 = String(train);
%             v3 = String('-T');
%             v4 = String(test);      
%             options = cat(1,v1,v2,v3,v4);
% 
%             classifier.buildClassifier(dataset);
%             temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
%             result=sprintf("\nPerformace of Random Forest on GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            result=""
            v1 = String('-t');
            v2 = String(train);
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of Random Forest on GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = testset.numInstances;

            classes = zeros(numInst,1);
            pred=''
            for i=1:numInst
                classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                if(strcmp(classes(i),"CN"))
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'CN');
                elseif(strcmp(classes(i),"EMCI"))
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'EMCI');
                elseif(strcmp(classes(i),"MCI"))
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'MCI');
                elseif(strcmp(classes(i),"LMCI"))
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'LMCI');
                else
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'AD');
                end
            end

            if app.imageAtAxes~="ManuHippo"

                train=append('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\',app.file2,'Train.csv');
                test=append('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\',app.file2,'Test.csv');
        

                conv.setFile(javaObject('java.io.File',train));
                dataset=conv.getDataSet;
                dataset.toString;
                dataset.setClassIndex(dataset.numAttributes() - 1);
                conv.setFile(javaObject('java.io.File',test));
                testset=conv.getDataSet;
                testset.toString;
                testset.setClassIndex(testset.numAttributes() - 1);
                conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\Shapeusertest.csv'));
                utestset=conv.getDataSet;
                utestset.toString;
                utestset.setClassIndex(utestset.numAttributes() - 1);
                
%     
%                 v1 = String('-t');
%                 v2 = String(train);
%                 v3 = String('-T');     
%                 v4 = String(test);       
%                 options = cat(1,v1,v2,v3,v4);
    
                classifier = javaObject('weka.classifiers.trees.RandomForest');
    
%                 temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
%                 result=sprintf("%s\n\n\n\nPerformace of Random Forest on Shape Features FOR TEST DATASET %s\n\n",result,temp);  
    
                result=""
                v1 = String('-t');
                v2 = String(train);
                v3 = String('-x');     
                v4 = String('3');       
                options = cat(1,v1,v2,v3,v4);
                
                temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
                result=sprintf("%s\n\n\nPerformace of Random Forest on Shape Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
                
                numInst = utestset.numInstances;

                classes = zeros(numInst,1);
                for i=1:numInst
                    classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                    if(strcmp(classes(i),"CN"))
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'CN');
                    elseif(strcmp(classes(i),"EMCI"))
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'EMCI');
                    elseif(strcmp(classes(i),"MCI"))
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'MCI');
                    elseif(strcmp(classes(i),"LMCI"))
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'LMCI');
                    else
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'AD');
                    end
                end   
            end
            app.EvaluationofmodelTextArea.Value=result;  
            app.ResultTextArea.Value=pred;  
        end

        % Menu selected function: AdaBoosterMenu
        function adaClass(app, event)
            javaaddpath('C:\Program Files\MATLAB\R2022b\java\jar\weka.jar');
            import java.util.Enumeration
            import java.lang.String
            import weka.classifiers.Classifier.*;
            import weka.classifiers.*;
            import weka.classifiers.Evaluation.*
            import weka.classifiers.trees.J48.*
            import weka.classifiers.trees.RandomForest.*
            import weka.classifiers.lazy.IBk.*
            import weka.classifiers.misc.AdaBoostM1.*
            import weka.core.converters.CSVLoader.*;
            import java.io.FileReader           
            import weka.core.Instances.*
            import weka.core.Utils.*
            import weka.core.Attribute.*
            import java.lang.System

            train=append('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\',app.file1,'Train.csv');
            test=append('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\',app.file1,'Test.csv');
        
        
            conv= javaObject("weka.core.converters.CSVLoader");
            conv.setFile(javaObject('java.io.File',train));
            dataset=conv.getDataSet;
            dataset.toString;
            dataset.setClassIndex(dataset.numAttributes() - 1);       
            conv.setFile(javaObject('java.io.File',test));
            testset=conv.getDataSet;
            testset.toString;
            testset.setClassIndex(testset.numAttributes() - 1);
            conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\GLCMusertest.csv'));
            utestset=conv.getDataSet;
            utestset.toString;
            utestset.setClassIndex(utestset.numAttributes() - 1);

            classifier = javaObject('weka.classifiers.meta.AdaBoostM1');  
%             v1 = String('-t');
%             v2 = String(train);
%             v3 = String('-T');
%             v4 = String(test);      
%             options = cat(1,v1,v2,v3,v4);
% 
%             classifier.buildClassifier(dataset);
%             temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
%             result=sprintf("\nPerformace of AdaBoost on GLCM, textural, statistical, histogram Features FOR TEST DATASET %s\n\n",temp);

            result=""
            v1 = String('-t');
            v2 = String(train);
            v3 = String('-x');     
            v4 = String('3');       
            options = cat(1,v1,v2,v3,v4);
            
            temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
            result=sprintf("%s\n\n\nPerformace of AdaBoost on GLCM, textural, statistical, histogram Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);
            
            numInst = testset.numInstances;

            classes = zeros(numInst,1);
            pred=''
            for i=1:numInst
                classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                if(strcmp(classes(i),"CN"))
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'CN');
                elseif(strcmp(classes(i),"EMCI"))
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'EMCI');
                elseif(strcmp(classes(i),"MCI"))
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'MCI');
                elseif(strcmp(classes(i),"LMCI"))
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'LMCI');
                else
                    pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'AD');
                end
            end

            if app.imageAtAxes~="ManuHippo"

                train=append('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\',app.file2,'Train.csv');
                test=append('C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\',app.file2,'Test.csv');
        

                conv.setFile(javaObject('java.io.File',train));
                dataset=conv.getDataSet;
                dataset.toString;
                dataset.setClassIndex(dataset.numAttributes() - 1);
                conv.setFile(javaObject('java.io.File',test));
                testset=conv.getDataSet;
                testset.toString;
                testset.setClassIndex(testset.numAttributes() - 1);
                conv.setFile(javaObject('java.io.File','C:\Abirami\sem7\fyp\dataset\Alzheimers-ADNI\ExtractedData\multiclass\Shapeusertest.csv'));
                utestset=conv.getDataSet;
                utestset.toString;
                utestset.setClassIndex(utestset.numAttributes() - 1);
                
%     
%                 v1 = String('-t');
%                 v2 = String(train);
%                 v3 = String('-T');     
%                 v4 = String(test);       
%                 options = cat(1,v1,v2,v3,v4);
    
                classifier = javaObject('weka.classifiers.meta.AdaBoostM1');
    
%                 temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
%                 result=sprintf("%s\n\n\n\nPerformace of AdaBoost on Shape Features FOR TEST DATASET %s\n\n",result,temp);  
    
                result=""
                v1 = String('-t');
                v2 = String(train);
                v3 = String('-x');     
                v4 = String('3');       
                options = cat(1,v1,v2,v3,v4);
                
                temp=(weka.classifiers.Evaluation.evaluateModel(classifier,options));
                result=sprintf("%s\n\n\nPerformace of AdaBoost on Shape Features FOR 3-FOLD CROSS VALIDATION %s\n\n",result,temp);

                numInst = utestset.numInstances;

                classes = zeros(numInst,1);
                for i=1:numInst
                    classes(i) = classifier.classifyInstance(utestset.instance(i-1));
                    if(strcmp(classes(i),"CN"))
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'CN');
                    elseif(strcmp(classes(i),"EMCI"))
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'EMCI');
                    elseif(strcmp(classes(i),"MCI"))
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'MCI');
                    elseif(strcmp(classes(i),"LMCI"))
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'LMCI');
                    else
                        pred=sprintf("%s\n\nGLCM Features: %s\n\n\n\n",pred,'AD');
                    end
                end   
            end
            app.EvaluationofmodelTextArea.Value=result;  
            app.ResultTextArea.Value=pred;  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.optionsMenu = uimenu(app.UIFigure);
            app.optionsMenu.Text = 'options';
            app.SkullStrippedMenu = uimenu(app.optionsMenu);
            app.SkullStrippedMenu.MenuSelectedFcn = createCallbackFcn(app, @skullStrip, true);
            app.SkullStrippedMenu.Text = 'Skull Stripped';
            app.CroppedROIMenu = uimenu(app.optionsMenu);
            app.CroppedROIMenu.MenuSelectedFcn = createCallbackFcn(app, @croppedHippo, true);
            app.CroppedROIMenu.Text = 'Cropped ROI';
            app.ManualROIMenu = uimenu(app.optionsMenu);
            app.ManualROIMenu.MenuSelectedFcn = createCallbackFcn(app, @manualClusterHippo, true);
            app.ManualROIMenu.Text = 'Manual ROI';
            app.Cluster1Menu = uimenu(app.ManualROIMenu);
            app.Cluster1Menu.MenuSelectedFcn = createCallbackFcn(app, @setC1, true);
            app.Cluster1Menu.Text = 'Cluster 1';
            app.Cluster2Menu = uimenu(app.ManualROIMenu);
            app.Cluster2Menu.MenuSelectedFcn = createCallbackFcn(app, @setC2, true);
            app.Cluster2Menu.Text = 'Cluster 2';
            app.Cluster3Menu = uimenu(app.ManualROIMenu);
            app.Cluster3Menu.MenuSelectedFcn = createCallbackFcn(app, @setC3, true);
            app.Cluster3Menu.Text = 'Cluster 3';
            app.Cluster4Menu = uimenu(app.ManualROIMenu);
            app.Cluster4Menu.MenuSelectedFcn = createCallbackFcn(app, @setC4, true);
            app.Cluster4Menu.Text = 'Cluster 4';
            app.Cluster5Menu = uimenu(app.ManualROIMenu);
            app.Cluster5Menu.MenuSelectedFcn = createCallbackFcn(app, @setC5, true);
            app.Cluster5Menu.Text = 'Cluster 5';
            app.Cluster6Menu = uimenu(app.ManualROIMenu);
            app.Cluster6Menu.MenuSelectedFcn = createCallbackFcn(app, @setC6, true);
            app.Cluster6Menu.Text = 'Cluster 6';
            app.AutomatedROIMenu = uimenu(app.optionsMenu);
            app.AutomatedROIMenu.MenuSelectedFcn = createCallbackFcn(app, @autoROI, true);
            app.AutomatedROIMenu.Text = 'Automated ROI';
            app.SegmentedROIMenu = uimenu(app.optionsMenu);
            app.SegmentedROIMenu.MenuSelectedFcn = createCallbackFcn(app, @segmentROI, true);
            app.SegmentedROIMenu.Text = 'Segmented ROI';
            app.ClassifyMenu = uimenu(app.UIFigure);
            app.ClassifyMenu.Text = 'Classify';
            app.KNNMenu = uimenu(app.ClassifyMenu);
            app.KNNMenu.MenuSelectedFcn = createCallbackFcn(app, @KNNClassify, true);
            app.KNNMenu.Text = 'KNN';
            app.DecisionTreeMenu = uimenu(app.ClassifyMenu);
            app.DecisionTreeMenu.MenuSelectedFcn = createCallbackFcn(app, @dfClassify, true);
            app.DecisionTreeMenu.Text = 'Decision Tree';
            app.RandomForestMenu = uimenu(app.ClassifyMenu);
            app.RandomForestMenu.MenuSelectedFcn = createCallbackFcn(app, @randomForestClass, true);
            app.RandomForestMenu.Text = 'Random Forest';
            app.AdaBoosterMenu = uimenu(app.ClassifyMenu);
            app.AdaBoosterMenu.MenuSelectedFcn = createCallbackFcn(app, @adaClass, true);
            app.AdaBoosterMenu.Text = 'AdaBooster';
            app.inpImg = uiaxes(app.UIFigure);
            title(app.inpImg, 'Input ')
            xlabel(app.inpImg, 'X')
            ylabel(app.inpImg, 'Y')
            zlabel(app.inpImg, 'Z')
            app.imgUsed = uiaxes(app.UIFigure);
            title(app.imgUsed, 'Image used')
            xlabel(app.imgUsed, 'X')
            ylabel(app.imgUsed, 'Y')
            zlabel(app.imgUsed, 'Z')
            app.InputButton = uibutton(app.UIFigure, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @importImage, true);
            app.InputButton.Text = 'Input';
            app.EvaluationofmodelTextAreaLabel = uilabel(app.UIFigure);
            app.EvaluationofmodelTextAreaLabel.Text = 'Evaluation of model';
            app.EvaluationofmodelTextArea = uitextarea(app.UIFigure);
            app.ResultTextAreaLabel = uilabel(app.UIFigure);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.ExtractFeaturesButton = uibutton(app.UIFigure, 'push');
            app.ExtractFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @classify, true);
            app.ExtractFeaturesButton.Text = 'Extract Features';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multiclass

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end