classdef skullStripping < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        RightPanel            matlab.ui.container.Panel
        medianfilterButton    matlab.ui.control.Button
        Clahe                 matlab.ui.control.Button
        EnhancementButton     matlab.ui.control.Button
        LoadButton            matlab.ui.control.Button
        filter                matlab.ui.control.UIAxes
        CLAHE                 matlab.ui.control.UIAxes
        graph                 matlab.ui.control.UIAxes
        before                matlab.ui.control.UIAxes
        SkullStrippingButton  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        LoadedImg % Description
        bw % Description
        hgraph % Description
        equalizedImg
        claheImg
        filterImg
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function loadData(app, event)
            [file,path]= uigetfile('*.*');
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            app.LoadedImg  = imread(fullfile(path,file));
            imshow(app.LoadedImg ,'parent',app.before);
        end

        % Button pushed function: SkullStrippingButton
        function strip(app, event)
            % segment the ROI
            figure(app.UIFigure);
            thr=15; % intensity similarity tolerance
            app.bw=regiongrowing(double(app.LoadedImg),256,256,thr);
            % fill in the holes
            app.bw=imfill(app.bw,'holes');
            imshow(app.bw,'parent',app.before);
        end

        % Button pushed function: EnhancementButton
        function hist(app, event)
            figure(app.UIFigure);
            imhist(app.LoadedImg)
            app.equalizedImg=histeq(app.LoadedImg);
            imshow(app.equalizedImg,'parent',app.graph)
        end

        % Button down function: CLAHE
        function clahe(app, event)
            figure(app.UIFigure);
            app.claheImg=adapthisteq(app.equalizedImg,'clipLimit',0.02,'Distribution','rayleigh');
            imshow(app.claheImg,'parent',app.CLAHE);
            %imshowpair(I,J,'montage');
        end

        % Button pushed function: Clahe
        function cl(app, event)
            figure(app.UIFigure);
            app.claheImg=adapthisteq(app.equalizedImg,'clipLimit',0.02,'Distribution','rayleigh');
            imshow(app.claheImg,'parent',app.CLAHE);
            %imshowpair(I,J,'montage');
        end

        % Button pushed function: medianfilterButton
        function median(app, event)
           figure(app.UIFigure);
            app.filterImg = medfilt2(app.claheImg,[3,3]);
            app.filterImg = imadjust(app.claheImg);
            imshow(app.filterImg,'parent',app.filter);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {736, 736};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.before = uiaxes(app.RightPanel);
            title(app.before, 'before')
            xlabel(app.before, 'X')
            ylabel(app.before, 'Y')
            zlabel(app.before, 'Z')
            app.graph = uiaxes(app.RightPanel);
            title(app.graph, 'hist')
            xlabel(app.graph, 'X')
            ylabel(app.graph, 'Y')
            zlabel(app.graph, 'Z')
            app.CLAHE = uiaxes(app.RightPanel);
            title(app.CLAHE, 'Title')
            xlabel(app.CLAHE, 'X')
            ylabel(app.CLAHE, 'Y')
            zlabel(app.CLAHE, 'Z')
            app.CLAHE.ButtonDownFcn = createCallbackFcn(app, @clahe, true);
            app.filter = uiaxes(app.RightPanel);
            title(app.filter, 'Filter')
            xlabel(app.filter, 'X')
            ylabel(app.filter, 'Y')
            zlabel(app.filter, 'Z')
            app.LoadButton = uibutton(app.RightPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadData, true);
            app.LoadButton.Text = 'Load';
            app.EnhancementButton = uibutton(app.RightPanel, 'push');
            app.EnhancementButton.ButtonPushedFcn = createCallbackFcn(app, @hist, true);
            app.EnhancementButton.Text = 'Enhancement';
            app.Clahe = uibutton(app.RightPanel, 'push');
            app.Clahe.ButtonPushedFcn = createCallbackFcn(app, @cl, true);
            app.Clahe.Text = 'clahe';
            app.medianfilterButton = uibutton(app.RightPanel, 'push');
            app.medianfilterButton.ButtonPushedFcn = createCallbackFcn(app, @median, true);
            app.medianfilterButton.Text = 'median filter';
            app.SkullStrippingButton = uibutton(app.UIFigure, 'push');
            app.SkullStrippingButton.ButtonPushedFcn = createCallbackFcn(app, @strip, true);
            app.SkullStrippingButton.Text = 'Skull Stripping';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = skullStripping

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end