classdef skullStrippingFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        JustCannyButton           matlab.ui.control.Button
        AreaofskullremovedwithrespecttobrainEditField  matlab.ui.control.NumericEditField
        AreaofskullremovedwithrespecttobrainEditFieldLabel  matlab.ui.control.Label
        edgepixelsEditField       matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        ErodeButton               matlab.ui.control.Button
        CleanButton               matlab.ui.control.Button
        ClaheButton               matlab.ui.control.Button
        BianrizeButton            matlab.ui.control.Button
        RemoveSkullButton         matlab.ui.control.Button
        CannyafterdilationButton  matlab.ui.control.Button
        LoadButton                matlab.ui.control.Button
        erodedImg                 matlab.ui.control.UIAxes
        cleanedImg                matlab.ui.control.UIAxes
        histEqClahe               matlab.ui.control.UIAxes
        Stripped                  matlab.ui.control.UIAxes
        otsuThreshold             matlab.ui.control.UIAxes
        edgeDet                   matlab.ui.control.UIAxes
        inputImg                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        LoadedImg % Description
        edgeImg % Description
        value % Description
        counts % Description
        thresh % Description
        threshImg % Description
        finalImg % Description
        claheImg % Description
        filledImg % Description
        erodeLayersImg % Description
        se % Description
        flag
        temp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function getInput(app, event)
            [file,path]= uigetfile('*.*');
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            app.LoadedImg  = imread(fullfile(path,file));
            imshow(app.LoadedImg ,'parent',app.inputImg);

        end

        % Button pushed function: CannyafterdilationButton
        function gaussian(app, event)
            app.edgeImg=edge(app.claheImg,'canny');
            figure(app.UIFigure);            
            app.se=strel('disk',1);
            %app.edgeImg=imclose(app.edgeImg,app.se);
            app.edgeImg=imdilate(app.edgeImg,app.se);

            imshow(app.edgeImg,'parent',app.edgeDet);            
            app.edgepixelsEditField.Value=sum(app.edgeImg(:));
        end

        % Button pushed function: BianrizeButton
        function binaryConv(app, event)
           % app.value = app.Slider.Value;
            % [app.counts,app.value] = imhist(app.claheImg);
           %  app.thresh=otsuthresh(app.counts);

            %app.thresh=median(app.claheImg(:));
            %app.thresh=app.thresh*255;
            %app.thresh=190;

            %app.thresh=max(app.counts);


            %app.threshImg=imbinarize(app.claheImg,20);

            app.threshImg=app.claheImg > 55;
            app.threshImg=bwareaopen(app.threshImg,10);
            figure(app.UIFigure);
            imshow(app.threshImg,'parent',app.otsuThreshold);
        end

        % Callback function: RemoveSkullButton, Stripped
        function stripSkull(app, event)
            app.finalImg=app.LoadedImg;
            app.finalImg(~app.erodeLayersImg)=0;
            figure(app.UIFigure);
            imshow(app.finalImg,'parent',app.Stripped);
            app.AreaofskullremovedwithrespecttobrainEditField.Value=(sum(~app.finalImg(:))/(sum(~app.LoadedImg(:))))*100;
        end

        % Button pushed function: ClaheButton
        function clahe(app, event)
            figure(app.UIFigure);
            app.claheImg=adapthisteq(app.LoadedImg,'clipLimit',0.02,'Distribution','rayleigh');
            imshow(app.claheImg,'parent',app.histEqClahe);
        end

        % Button pushed function: CleanButton
        function fillholes(app, event)
            app.filledImg=imfill(app.threshImg,'holes');
            figure(app.UIFigure);
            imshow(app.filledImg,'parent',app.cleanedImg);
        end

        % Button pushed function: ErodeButton
        function erodeLayers(app, event)
            app.flag=0;
            for i=1: 15
                app.se=strel('disk',4,0);
                app.erodeLayersImg=imerode(app.filledImg,app.se);
                app.temp = (numel(str2double(app.se))-sum(str2double(app.se(:)))/numel(str2double(app.se))*100);
                if(app.temp <15)
                    disp("hi");
                    if(app.flag==0)
                        app.flag=app.flag+1;
                    end
                end
                if app.flag==1
                    app.temp=(str2double(numel(app.se))-sum(str2double(app.se(:)))/numel(str2double(app.se))*100) ;
                    if(app.temp>80)
                        if(app.flag==0)
                            app.flag=app.flag+1;
                        end
                    end
                end
                if app.flag==2
                    break;
                end
                
            end
            figure(app.UIFigure);
            imshow(app.erodeLayersImg,'parent',app.erodedImg);
        end

        % Callback function
        function sobelEdge(app, event)
            app.edgeImg=edge(app.LoadedImg,'sobel');
            figure(app.UIFigure);
            imshow(app.edgeImg,'parent',app.edgeDet);
            app.edgepixelsEditField.Value=sum(app.edgeImg(:));
        end

        % Callback function
        function prewittEdge(app, event)
            app.edgeImg=edge(app.LoadedImg,'prewitt');
            figure(app.UIFigure);
            imshow(app.edgeImg,'parent',app.edgeDet);
            app.edgepixelsEditField.Value=sum(app.edgeImg(:));
        end

        % Callback function
        function robertsEdge(app, event)
            app.edgeImg=edge(app.LoadedImg,'roberts');
            figure(app.UIFigure);
            imshow(app.edgeImg,'parent',app.edgeDet);
            app.edgepixelsEditField.Value=sum(app.edgeImg(:));
        end

        % Callback function
        function logedge(app, event)
            app.edgeImg=edge(app.LoadedImg,'log');
            figure(app.UIFigure);
            imshow(app.edgeImg,'parent',app.edgeDet);
            app.edgepixelsEditField.Value=sum(app.edgeImg(:));
        end

        % Button pushed function: JustCannyButton
        function justCanny(app, event)
            app.edgeImg=edge(app.claheImg,'canny');
            figure(app.UIFigure);
            imshow(app.edgeImg,'parent',app.edgeDet);         
            app.edgepixelsEditField.Value=sum(app.edgeImg(:));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.inputImg = uiaxes(app.UIFigure);
            title(app.inputImg, 'INPUT')
            xlabel(app.inputImg, 'X')
            ylabel(app.inputImg, 'Y')
            zlabel(app.inputImg, 'Z')
            app.edgeDet = uiaxes(app.UIFigure);
            title(app.edgeDet, 'EDGE DETECTION')
            xlabel(app.edgeDet, 'X')
            ylabel(app.edgeDet, 'Y')
            zlabel(app.edgeDet, 'Z')
            app.otsuThreshold = uiaxes(app.UIFigure);
            title(app.otsuThreshold, 'BINARY IMAGE')
            xlabel(app.otsuThreshold, 'X')
            ylabel(app.otsuThreshold, 'Y')
            zlabel(app.otsuThreshold, 'Z')
            app.Stripped = uiaxes(app.UIFigure);
            title(app.Stripped, 'FINAL IMAGE')
            xlabel(app.Stripped, 'X')
            ylabel(app.Stripped, 'Y')
            zlabel(app.Stripped, 'Z')
            app.Stripped.ButtonDownFcn = createCallbackFcn(app, @stripSkull, true);
            app.histEqClahe = uiaxes(app.UIFigure);
            title(app.histEqClahe, 'HISTOGRAM EQUALIZATION ')
            xlabel(app.histEqClahe, 'X')
            ylabel(app.histEqClahe, 'Y')
            zlabel(app.histEqClahe, 'Z')
            app.cleanedImg = uiaxes(app.UIFigure);
            title(app.cleanedImg, 'CLEANED BINARY IMAGE')
            xlabel(app.cleanedImg, 'X')
            ylabel(app.cleanedImg, 'Y')
            zlabel(app.cleanedImg, 'Z')
            app.erodedImg = uiaxes(app.UIFigure);
            title(app.erodedImg, 'ERODED BINARY IMAGE')
            xlabel(app.erodedImg, 'X')
            ylabel(app.erodedImg, 'Y')
            zlabel(app.erodedImg, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @getInput, true);
            app.LoadButton.Text = 'Load';
            app.CannyafterdilationButton = uibutton(app.UIFigure, 'push');
            app.CannyafterdilationButton.ButtonPushedFcn = createCallbackFcn(app, @gaussian, true);
            app.CannyafterdilationButton.Text = 'Canny after dilation';
            app.RemoveSkullButton = uibutton(app.UIFigure, 'push');
            app.RemoveSkullButton.ButtonPushedFcn = createCallbackFcn(app, @stripSkull, true);
            app.RemoveSkullButton.Text = 'Remove Skull';
            app.BianrizeButton = uibutton(app.UIFigure, 'push');
            app.BianrizeButton.ButtonPushedFcn = createCallbackFcn(app, @binaryConv, true);
            app.BianrizeButton.Text = 'Bianrize';
            app.ClaheButton = uibutton(app.UIFigure, 'push');
            app.ClaheButton.ButtonPushedFcn = createCallbackFcn(app, @clahe, true);
            app.ClaheButton.Text = 'Clahe';
            app.CleanButton = uibutton(app.UIFigure, 'push');
            app.CleanButton.ButtonPushedFcn = createCallbackFcn(app, @fillholes, true);
            app.CleanButton.Text = 'Clean';
            app.ErodeButton = uibutton(app.UIFigure, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @erodeLayers, true);
            app.ErodeButton.Text = 'Erode';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'edge pixels ';
            app.edgepixelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AreaofskullremovedwithrespecttobrainEditFieldLabel = uilabel(app.UIFigure);
            app.AreaofskullremovedwithrespecttobrainEditFieldLabel.Text = 'Area of skull removed with respect to brain';
            app.AreaofskullremovedwithrespecttobrainEditField = uieditfield(app.UIFigure, 'numeric');
            app.JustCannyButton = uibutton(app.UIFigure, 'push');
            app.JustCannyButton.ButtonPushedFcn = createCallbackFcn(app, @justCanny, true);
            app.JustCannyButton.Text = 'Just Canny';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = skullStrippingFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end